classdef info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InformacinPanel              matlab.ui.container.Panel
        SignalVisualizerLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        DirectoradelproyectoLabel    matlab.ui.control.Label
        InmaHernezRiojaLabel         matlab.ui.control.Label
        DesarrolladorLabel           matlab.ui.control.Label
        EderdelBlancoSierraLabel     matlab.ui.control.Label
        LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel  matlab.ui.control.Label
        AyudadeusuarioLabel          matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ES_URL                       matlab.ui.control.EditField
        InformazioaPanel             matlab.ui.container.Panel
        SignalVisualizerLabel_2      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        ProiektuarenzuzendariaLabel  matlab.ui.control.Label
        InmaHernezRiojaLabel_2       matlab.ui.control.Label
        GaratzaileaLabel             matlab.ui.control.Label
        EderdelBlancoSierraLabel_2   matlab.ui.control.Label
        AHOLABSeinaleprozesamendukolaborategiaEHUUPVLabel  matlab.ui.control.Label
        ErabiltzailearentzakolaguntzaLabel  matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        EUS_URL                      matlab.ui.control.EditField
        InformationPanel             matlab.ui.container.Panel
        SignalVisualizerLabel_3      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        PrejectdirectorLabel         matlab.ui.control.Label
        InmaHernezRiojaLabel_3       matlab.ui.control.Label
        DeveloperLabel               matlab.ui.control.Label
        EderdelBlancoSierraLabel_3   matlab.ui.control.Label
        AHOLABSignalProcessingLaboratoryEHUUPVLabel  matlab.ui.control.Label
        UserhelpLabel                matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        EN_URL                       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingApp % Description
        url % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, lang_ind)
            app.callingApp = mainApp;
            
            switch lang_ind % Muestra el panel correspondiente al idioma
                case 1
                    app.InformationPanel.Visible = 'on';
                    app.url = strcat(getenv('PROGRAMFILES'),filesep,'Aholab',filesep,'SignalVisualizer',filesep,'application',filesep,'Help files',filesep,'en',filesep,'Userhelp.html');
                    app.EN_URL.Value = app.url;
                case 2
                    app.InformacinPanel.Visible = 'on';
                    app.url = strcat(getenv('PROGRAMFILES'),filesep,'Aholab',filesep,'SignalVisualizer',filesep,'application',filesep,'Help files',filesep,'es',filesep,'Ayudadeusuario.html');
                    app.ES_URL.Value = app.url;
                case 3
                    app.InformazioaPanel.Visible = 'on';
                    app.url = strcat(getenv('PROGRAMFILES'),filesep,'Aholab',filesep,'SignalVisualizer',filesep,'application',filesep,'Help files',filesep,'en',filesep,'Erabiltzailearentzakolaguntza.html');
                    app.EUS_URL.Value = app.url;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Al cerrar la ventana con el botón
            % Habilita los menús de la app principal
            if isvalid(app.callingApp)
                app.callingApp.SignalVisualizerMenu.Enable = 'on';
                app.callingApp.GenerarMenu.Enable = 'on';
                app.callingApp.InputMenu.Enable = 'on';
                app.callingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InformacinPanel = uipanel(app.UIFigure);
            app.InformacinPanel.Title = 'Información';
            app.SignalVisualizerLabel = uilabel(app.InformacinPanel);
            app.SignalVisualizerLabel.Text = 'Signal Visualizer';
            app.Label = uilabel(app.InformacinPanel);
            app.Label.Text = 'Colaboración entre Euskal Herriko Unibertsitatea/Universidad del País Vasco y Musikene';
            app.DirectoradelproyectoLabel = uilabel(app.InformacinPanel);
            app.DirectoradelproyectoLabel.Text = 'Directora del proyecto:';
            app.InmaHernezRiojaLabel = uilabel(app.InformacinPanel);
            app.InmaHernezRiojaLabel.Text = 'Inma Hernáez Rioja';
            app.DesarrolladorLabel = uilabel(app.InformacinPanel);
            app.DesarrolladorLabel.Text = 'Desarrollador:';
            app.EderdelBlancoSierraLabel = uilabel(app.InformacinPanel);
            app.EderdelBlancoSierraLabel.Text = 'Eder del Blanco Sierra';
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel = uilabel(app.InformacinPanel);
            app.LaboratoriodeprocesamientodesealAHOLABEHUUPVLabel.Text = 'Laboratorio de procesamiento de señal AHOLAB (EHU/UPV)';
            app.AyudadeusuarioLabel = uilabel(app.InformacinPanel);
            app.AyudadeusuarioLabel.Visible = 'off';
            app.AyudadeusuarioLabel.Text = 'Ayuda de usuario:';
            app.Label_2 = uilabel(app.InformacinPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'Para acceder a la ayuda de usuario, copiar el enlace superior en un navegador web.';
            app.ES_URL = uieditfield(app.InformacinPanel, 'text');
            app.ES_URL.Editable = 'off';
            app.ES_URL.Visible = 'off';
            app.InformazioaPanel = uipanel(app.UIFigure);
            app.InformazioaPanel.Title = 'Informazioa';
            app.InformazioaPanel.Visible = 'off';
            app.SignalVisualizerLabel_2 = uilabel(app.InformazioaPanel);
            app.SignalVisualizerLabel_2.Text = 'Signal Visualizer';
            app.Label_3 = uilabel(app.InformazioaPanel);
            app.Label_3.Text = 'Euskal Herriko Unibertsitatea/Universidad del País Vasco-ren eta Musikene-ren arteko lankidetza.';
            app.ProiektuarenzuzendariaLabel = uilabel(app.InformazioaPanel);
            app.ProiektuarenzuzendariaLabel.Text = 'Proiektuaren zuzendaria:';
            app.InmaHernezRiojaLabel_2 = uilabel(app.InformazioaPanel);
            app.InmaHernezRiojaLabel_2.Text = 'Inma Hernáez Rioja';
            app.GaratzaileaLabel = uilabel(app.InformazioaPanel);
            app.GaratzaileaLabel.Text = 'Garatzailea:';
            app.EderdelBlancoSierraLabel_2 = uilabel(app.InformazioaPanel);
            app.EderdelBlancoSierraLabel_2.Text = 'Eder del Blanco Sierra';
            app.AHOLABSeinaleprozesamendukolaborategiaEHUUPVLabel = uilabel(app.InformazioaPanel);
            app.AHOLABSeinaleprozesamendukolaborategiaEHUUPVLabel.Text = 'AHOLAB Seinale prozesamenduko laborategia (EHU/UPV)';
            app.ErabiltzailearentzakolaguntzaLabel = uilabel(app.InformazioaPanel);
            app.ErabiltzailearentzakolaguntzaLabel.Visible = 'off';
            app.ErabiltzailearentzakolaguntzaLabel.Text = 'Erabiltzailearentzako laguntza:';
            app.Label_4 = uilabel(app.InformazioaPanel);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Erabiltzailearentzako laguntza eskuratzeko, kopiatu goiko esteka web-nabigatzaile batean.';
            app.EUS_URL = uieditfield(app.InformazioaPanel, 'text');
            app.EUS_URL.Editable = 'off';
            app.EUS_URL.Visible = 'off';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Visible = 'off';
            app.SignalVisualizerLabel_3 = uilabel(app.InformationPanel);
            app.SignalVisualizerLabel_3.Text = 'Signal Visualizer';
            app.Label_5 = uilabel(app.InformationPanel);
            app.Label_5.Text = 'A collaboration between the University of the Basque Country (EHU/UPV) and Musikene.';
            app.PrejectdirectorLabel = uilabel(app.InformationPanel);
            app.PrejectdirectorLabel.Text = 'Preject director:';
            app.InmaHernezRiojaLabel_3 = uilabel(app.InformationPanel);
            app.InmaHernezRiojaLabel_3.Text = 'Inma Hernáez Rioja';
            app.DeveloperLabel = uilabel(app.InformationPanel);
            app.DeveloperLabel.Text = 'Developer:';
            app.EderdelBlancoSierraLabel_3 = uilabel(app.InformationPanel);
            app.EderdelBlancoSierraLabel_3.Text = 'Eder del Blanco Sierra';
            app.AHOLABSignalProcessingLaboratoryEHUUPVLabel = uilabel(app.InformationPanel);
            app.AHOLABSignalProcessingLaboratoryEHUUPVLabel.Text = 'AHOLAB Signal Processing Laboratory (EHU/UPV)';
            app.UserhelpLabel = uilabel(app.InformationPanel);
            app.UserhelpLabel.Visible = 'off';
            app.UserhelpLabel.Text = 'User help:';
            app.Label_6 = uilabel(app.InformationPanel);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = 'To access the user help, copy the above link into a web browser.';
            app.EN_URL = uieditfield(app.InformationPanel, 'text');
            app.EN_URL.Editable = 'off';
            app.EN_URL.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = info_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end