classdef load_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        playButton                 matlab.ui.control.Button
        AbrirficheroButton         matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        DuracinLabel               matlab.ui.control.Label
        fsField                    matlab.ui.control.NumericEditField
        fsLabel                    matlab.ui.control.Label
        durationField              matlab.ui.control.EditField
        stopButton                 matlab.ui.control.Button
        CargarButton               matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        fragmentoCheckBox          matlab.ui.control.CheckBox
        SeleccionarfragmentoLabel  matlab.ui.control.Label
        InicioLabel                matlab.ui.control.Label
        FinLabel                   matlab.ui.control.Label
        inicioMEditField           matlab.ui.control.EditField
        inicioSEditField           matlab.ui.control.EditField
        mLabel                     matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        finMEditField              matlab.ui.control.EditField
        finSEditField              matlab.ui.control.EditField
        mLabel_2                   matlab.ui.control.Label
        sLabel_2                   matlab.ui.control.Label
        CambiarseleccinButton      matlab.ui.control.Button
        inicioOkButton             matlab.ui.control.Button
        finOkButton                matlab.ui.control.Button
        infoLabelInicio            matlab.ui.control.Label
        infoLabelFin               matlab.ui.control.Label
        CargarficherodeaudioLabel  matlab.ui.control.Label
        infoButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        signal % Description
        t
        fs
        player
        CallingApp % Description
        selectionState = 0; % Estado del ciclo de seleccion
        fragmentSelected = 0; % Si hay un fragmento seleccionado
        selectionStart = 0;
        selectionEnd = 0;
        audioPosition = 0;
        
        windowOriginalWidth = 780; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 543; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        
        duration_warning = 'La duración del fichero no puede superar los 5 minutos.';
        numerical_warning = 'El valor introducido debe ser numérico.';
        timeSelection_warning = 'Sobrepasa la duración del audio.';
        noSelection_warning = ['El comienzo y el final de la seección son el mismo.\n' ...
            'No se ha seleccionado ningún fragmento.'];
        window_warning = 'Cierra una ventana de visualización antes de abrir otra.';
        stereo_warning = 'El audio es estéreo.\n Se promediarán ambas pistas.';
        fs_warning = 'La frecuencia de muestreo debe ser al menos de 100 muestras/s.';
        duration2_warning = 'La duración del fragmento cargado debe ser de al menos 1 s.';
        
        visualizationName = 'Fichero de audio';
        plot_color;
        line_color;
        start_color;
        end_color;
        selection_color;
        
        help_url = '/User%20help/es/Ayudadeusuario.html#id.g7nm9lqhwdpn';
    end
    
    methods (Access = private)
        
        function success = showInfo(app,filename)
            info = audioinfo(filename);
            
            duration = info.Duration;
            
            
            if duration > 300
                warn = warndlg(app.duration_warning);
                uiwait(warn);
                success = 0;
                return
            elseif info.SampleRate < 100
                warn = warndlg(app.fs_warning);
                uiwait(warn);
                success = 0;
            else
                success = 1;
            end
            
            ss = mod(duration,60);
            mm = fix(duration/60);
            
            app.durationField.Value = sprintf('%02d:%02.3f',mm,ss);
            
            app.fsField.Value = info.SampleRate;
        end
        
        function loadFile(app,filename)
            [y,Fs] = audioread(filename);
            
            if min(size(y)) == 2
                warn = warndlg(app.stereo_warning);
                uiwait(warn);
                ampMax = max(max(abs(y))); % Amplitud máxima, para escalar después
                y = y(:,1) + y(:,2); % Stereo -> Mono
                y = y*ampMax/max(abs(y)); % Se normaliza y se deja con la misma amplitud máxima que tenía antes
            elseif min(size(y)) > 2
                warn = warndlg('Error');
                uiwait(warn);
                return
            end
            
            app.signal = y;
            app.t = linspace(0,length(y)/Fs,length(y))';
            app.fs = Fs;
            
            if max(abs(y)) > 1
                y = y/max(abs(y));
            end
            
            app.player = audioplayer(y,Fs);
            
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
            app.UIAxes.XLim = [0,max(app.t)];
        end
        
        function nuevaSeleccion(app)
            
            switch app.selectionState
                case 0
                    app.CargarButton.Enable = 'off'; % Inicia ciclo de selección
                    app.AbrirficheroButton.Enable = 'off';
                    app.playButton.Enable = 'off';
                    app.stopButton.Enable = 'off';
                    app.CambiarseleccinButton.Enable = 'off';
                    
                    app.infoLabelInicio.Visible = 'on'; % Inicializa primer estado
                    app.inicioMEditField.Enable = 'on';
                    app.inicioSEditField.Enable = 'on';
                    app.inicioOkButton.Enable = 'on';
                    
                    app.selectionState = 1;
                case 1
                    app.infoLabelInicio.Visible = 'off'; % Finalizar el estado anterior
                    app.inicioMEditField.Enable = 'off';
                    app.inicioSEditField.Enable = 'off';
                    app.inicioOkButton.Enable = 'off';
                    
                    app.infoLabelFin.Visible = 'on'; % Inicializar siguiente estado
                    app.finMEditField.Enable = 'on';
                    app.finSEditField.Enable = 'on';
                    app.finOkButton.Enable = 'on';
                    
                    app.selectionState = 2;
                case 2
                    app.infoLabelFin.Visible = 'off'; % Finalizar estado anterior
                    app.finMEditField.Enable = 'off';
                    app.finSEditField.Enable = 'off';
                    app.finOkButton.Enable = 'off';
                    app.CambiarseleccinButton.Enable = 'on';
                    
                    app.CargarButton.Enable = 'on'; % Finaliza ciclo de selección
                    app.AbrirficheroButton.Enable = 'on';
                    app.playButton.Enable = 'on';
                    app.stopButton.Enable = 'on';
                    
                    app.selectionState = 0;
                    
                    if app.selectionEnd == app.selectionStart
                        warn = warndlg(app.noSelection_warning);
                        uiwait(warn);
                        app.selectionStart = 0;
                        app.selectionEnd = 0;
                        app.fragmentSelected = 0;
                        app.inicioMEditField.Value = '00';
                        app.inicioSEditField.Value = '00.000';
                        app.finMEditField.Value = '00';
                        app.finSEditField.Value = '00.000';
                        
                        app.player = audioplayer(app.signal,app.fs);
                        
                        hold(app.UIAxes,'off')
                        plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
                        app.UIAxes.XLim = [0,max(app.t)];
                        return
                    elseif app.selectionEnd < app.selectionStart
                        corregirSeleccion(app);
                    end
                    y = app.signal(app.t > app.selectionStart & app.t < app.selectionEnd);
                    
                    app.player = audioplayer(y,app.fs);
                    
                    app.fragmentSelected = 1;
                    
                    dibujarSeleccion(app);
            end
        end
        
        function corregirSeleccion(app)
            % Si el tiempo de fin de selección elegido es anterior al
            % inicio de selección, se corrige intercambiándolos.
            aux = app.selectionStart;
            app.selectionStart = app.selectionEnd;
            app.selectionEnd = aux;
            
            aux = app.inicioMEditField.Value;
            app.inicioMEditField.Value = app.finMEditField.Value;
            app.finMEditField.Value = aux;
            
            aux = app.inicioSEditField.Value;
            app.inicioSEditField.Value = app.finSEditField.Value;
            app.finSEditField.Value = aux;
        end
        
        function dibujarSeleccion(app)
            xwidth = app.selectionEnd - app.selectionStart;
            yheight = app.UIAxes.YLim(2) - app.UIAxes.YLim(1);
            ymin = app.UIAxes.YLim(1);
            
            cla(app.UIAxes);
            hold(app.UIAxes,'off');
            
            rectangle(app.UIAxes,'Position',[app.selectionStart,ymin,xwidth,yheight],"FaceColor",[.9 .9 .9],'LineStyle',"none");
            
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
            plot(app.UIAxes,[app.selectionStart,app.selectionStart],app.UIAxes.YLim,"Color",app.start_color)
            plot(app.UIAxes,[app.selectionEnd,app.selectionEnd],app.UIAxes.YLim,"Color",app.end_color)
        end
        
        function dibujarInicioSeleccion(app)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[app.selectionStart,app.selectionStart],app.UIAxes.YLim,"Color",app.start_color)
        end
        
        function positionLine(app,pt)
            cla(app.UIAxes);
            hold(app.UIAxes,'off');
            plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
            
            if pt(1) < 0 % Si la selección es inválida, se anula la selección anterior
                app.audioPosition = 0;
                return
            end
            
            ptx = round(pt(1)*app.fs);
            app.audioPosition = app.t(ptx);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,'.-',"Color",app.line_color)
            y = app.signal(app.t > app.t(ptx));
            app.player = audioplayer(y,app.fs);
        end
        
        function selectStart(app,pt)
            if pt(1) < 0 % Si la selección es inválida, sale
                return
            end
            
            ptx = round(pt(1)*app.fs);
            app.selectionStart = app.t(ptx);
            app.inicioSEditField.Value = sprintf('%02.3f',mod(app.selectionStart,60));
            app.inicioMEditField.Value = sprintf('%02d',fix(app.selectionStart/60));
            
            dibujarInicioSeleccion(app)
            nuevaSeleccion(app)
        end
        
        function selectEnd(app,pt)
            if pt(1) < 0 % Si la selección es inválida, sale
                return
            end
            
            ptx = round(pt(1)*app.fs);
            app.selectionEnd = app.t(ptx);
            app.finSEditField.Value = sprintf('%02.3f',mod(app.selectionEnd,60));
            app.finMEditField.Value = sprintf('%02d',fix(app.selectionEnd/60));
            
            nuevaSeleccion(app)
        end
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 780;
            originalh = 543;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
        end
        
        function translate(app,language)
            load('language.mat','loadTranslate')
            
            app.UIFigure.Name = loadTranslate.name{language};
            app.UIAxes.XLabel.String = loadTranslate.time{language};
            
            app.AbrirficheroButton.Text = loadTranslate.openFile{language};
            app.CambiarseleccinButton.Text = loadTranslate.changeSelection{language};
            app.CargarButton.Text = loadTranslate.load{language};
            
            app.CargarficherodeaudioLabel.Text = loadTranslate.title{language};
            app.DuracinLabel.Text = loadTranslate.duration{language};
            app.SeleccionarfragmentoLabel.Text = loadTranslate.selectFragment{language};
            app.InicioLabel.Text = loadTranslate.start{language};
            app.FinLabel.Text = loadTranslate.end{language};
            app.infoLabelInicio.Text = loadTranslate.startMessage{language};
            app.infoLabelFin.Text = loadTranslate.endMessage{language};
            
            app.numerical_warning = loadTranslate.numerical_warning{language};
            app.window_warning = loadTranslate.window_warning{language};
            app.timeSelection_warning = loadTranslate.timeSelection_warning{language};
            app.noSelection_warning = loadTranslate.noSelection_warning{language};
            app.duration_warning = loadTranslate.duration_warning{language};
            app.stereo_warning = loadTranslate.stereo_warning{language};
            app.fs_warning = loadTranslate.fs_warning{language};
            app.duration2_warning = loadTranslate.duration2_warning{language};
            
            app.visualizationName = loadTranslate.visualizationName{language};
            
            app.help_url = loadTranslate.help_url{language};
            
            clear('loadTranslate')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data)
            app.CallingApp = mainApp;
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            app.start_color = user_data.start_color;
            app.end_color = user_data.end_color;
            app.selection_color = user_data.selection_color;
            app.UIAxes.Color = user_data.bg_color;
            
            bg_color = user_data.bg_color;
            
            % Selecciona el color del cursor para
            % que haya buen contraste
            if (bg_color(1)*0.299 + bg_color(2)*0.587 + bg_color(3)*0.114) < 0.73
               app.line_color = [1,1,1];
            else
               app.line_color = [0,0,0];
            end
            
            app.UIAxes.Interactions = [];
            app.UIAxes.Toolbar.Visible = 'off';
        end

        % Button pushed function: AbrirficheroButton
        function AbrirficheroButtonPushed(app, event)
            [filename,path] = uigetfile('*.mp3;*.wav;*.ogg;*.aiff');
            if filename == 0
                return
            end
            
            filename = strcat(path,filename);
            if showInfo(app,filename) == 0
                app.UIFigure.Visible = 'off'; % Al cargar el fichero, la ventana se queda en segundo plano
                app.UIFigure.Visible = 'on'; % Se hace esto para traerla al frente
                return
            end
            
            loadFile(app,filename)
            app.UIFigure.Visible = 'off'; % Al cargar el fichero, la ventana se queda en segundo plano
            app.UIFigure.Visible = 'on'; % Se hace esto para traerla al frente
            
            app.playButton.Enable = 'on';
            app.stopButton.Enable = 'on';
            app.CargarButton.Enable = 'on';
            app.fragmentoCheckBox.Enable = 'on';
            app.SeleccionarfragmentoLabel.FontColor = '#000000';
            
            if app.fragmentSelected
                app.InicioLabel.FontColor = [0.651, 0.651, 0.651];
                app.mLabel.FontColor = [0.651, 0.651, 0.651];
                app.sLabel.FontColor = [0.651, 0.651, 0.651];
                app.FinLabel.FontColor = [0.651, 0.651, 0.651];
                app.mLabel_2.FontColor = [0.651, 0.651, 0.651];
                app.sLabel_2.FontColor = [0.651, 0.651, 0.651];
                
                app.inicioMEditField.Value = '00';
                app.inicioSEditField.Value = '00.000';
                app.finMEditField.Value = '00';
                app.finSEditField.Value = '00.000';
                app.fragmentoCheckBox.Value = false;
                
                app.fragmentSelected = 0;
            end
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            
            play(app.player);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            if app.fragmentSelected
                if app.selectionEnd - app.selectionStart < 1
                    warn = warndlg(app.duration2_warning);
                    uiwait(warn);
                    return
                end
                app.signal = app.signal(app.t > app.selectionStart & app.t < app.selectionEnd);
                app.t = app.t(app.t > app.selectionStart & app.t < app.selectionEnd);
                app.t = app.t - app.t(1); % Para que la nueva señal comience en t = 0 s
            elseif app.t(end) < 1
                warn = warndlg(app.duration2_warning);
                uiwait(warn);
                return
            end
            
            
            success = createPlot(app.CallingApp,app.signal,app.t,app.visualizationName,'SoundFile',app.fs);
            
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Value changed function: fragmentoCheckBox
        function fragmentoCheckBoxValueChanged(app, event)
            if app.fragmentoCheckBox.Value
                if app.audioPosition ~= 0
                    hold(app.UIAxes,'off') % Limpia las marcas de selección
                    plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
                    app.UIAxes.XLim = [0,max(app.t)];
                    app.audioPosition = 0;
                end
                
                app.InicioLabel.FontColor = '#000000';
                app.mLabel.FontColor = '#000000';
                app.sLabel.FontColor = '#000000';
                app.FinLabel.FontColor = '#000000';
                app.mLabel_2.FontColor = '#000000';
                app.sLabel_2.FontColor = '#000000';
                
                nuevaSeleccion(app)
            else
                hold(app.UIAxes,'off') % Limpia las marcas de selección
                plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
                app.UIAxes.XLim = [0,max(app.t)];
                
                app.InicioLabel.FontColor = [0.651, 0.651, 0.651];
                app.mLabel.FontColor = [0.651, 0.651, 0.651];
                app.sLabel.FontColor = [0.651, 0.651, 0.651];
                app.FinLabel.FontColor = [0.651, 0.651, 0.651];
                app.mLabel_2.FontColor = [0.651, 0.651, 0.651];
                app.sLabel_2.FontColor = [0.651, 0.651, 0.651];
                
                app.inicioMEditField.Value = '00';
                app.inicioSEditField.Value = '00.000';
                app.finMEditField.Value = '00';
                app.finSEditField.Value = '00.000';
                
                app.inicioMEditField.Enable = 'off';
                app.inicioSEditField.Enable = 'off';
                app.finMEditField.Enable = 'off';
                app.finSEditField.Enable = 'off';
                
                app.infoLabelFin.Visible = 'off';
                app.infoLabelInicio.Visible = 'off';
                
                app.selectionState = 0;
                app.player = audioplayer(app.signal,app.fs);
                app.fragmentSelected = 0;
            end
            
        end

        % Value changed function: inicioMEditField
        function inicioMEditFieldValueChanged(app, event)
            str = app.inicioMEditField.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            end
            
            num = abs(num);
            
            if num > 10
                num = 10;
            end
            
            app.inicioMEditField.Value = sprintf('%02d',num);
        end

        % Value changed function: inicioSEditField
        function inicioSEditFieldValueChanged(app, event)
            str = app.inicioSEditField.Value;
            str = strrep(str,',','.');
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            end
            
            num = abs(num);
            
            if num > 59
                num = 59;
            end
            
            app.inicioSEditField.Value = sprintf('%02.3f',num);
        end

        % Value changed function: finMEditField
        function finMEditFieldValueChanged(app, event)
            str = app.finMEditField.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            end
            
            num = abs(num);
            
            if num > 10
                num = 10;
            end
            
            app.finMEditField.Value = sprintf('%02d',num);
        end

        % Value changed function: finSEditField
        function finSEditFieldValueChanged(app, event)
            str = app.finSEditField.Value;
            str = strrep(str,',','.');
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            end
            
            num = abs(num);
            
            if num > 59
                num = 59;
            end
            
            app.finSEditField.Value = sprintf('%02.3f',num);
        end

        % Button pushed function: inicioOkButton
        function inicioOkButtonPushed(app, event)
            timeSelection = str2double(app.inicioMEditField.Value)*60 + str2double(app.inicioSEditField.Value);
            
            if  timeSelection > app.t(end)
                warn = warndlg(app.timeSelection_warning);
                uiwait(warn);
            else
                [~,idx]=min(abs(app.t-timeSelection)); % Selecciona el valor más cercano en el array de tiempo
                timeSelection = app.t(idx);
                
                app.inicioMEditField.Value = sprintf('%02d',fix(timeSelection/60)); % Actualiza al valor real seleccionado
                app.inicioSEditField.Value = sprintf('%02.3f',mod(timeSelection,60));
                app.selectionStart = timeSelection;
                
                dibujarInicioSeleccion(app);
                nuevaSeleccion(app);
            end
        end

        % Button pushed function: finOkButton
        function finOkButtonPushed(app, event)
            timeSelection = str2double(app.finMEditField.Value)*60 + str2double(app.finSEditField.Value);
            
            if  timeSelection > app.t(end)
                warn = warndlg(app.timeSelection_warning);
                uiwait(warn);
            else
                [~,idx]=min(abs(app.t-timeSelection)); % Selecciona el valor más cercano en el array de tiempo
                timeSelection = app.t(idx);
                
                app.finMEditField.Value = sprintf('%02d',fix(timeSelection/60)); % Actualiza al valor real seleccionado
                app.finSEditField.Value = sprintf('%02.3f',mod(timeSelection,60));
                app.selectionEnd = timeSelection;
                
                nuevaSeleccion(app);
            end
        end

        % Button pushed function: CambiarseleccinButton
        function CambiarseleccinButtonPushed(app, event)
            app.inicioMEditField.Value = '00';
            app.inicioSEditField.Value = '00.000';
            app.finMEditField.Value = '00';
            app.finSEditField.Value = '00.000';
            
            hold(app.UIAxes,'off') % Limpia los marcadores de selección
            plot(app.UIAxes,app.t,app.signal,"Color",app.plot_color)
            app.UIAxes.XLim = [0,max(app.t)];
            
            app.fragmentSelected = 0;
            
            nuevaSeleccion(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if isempty(app.signal)
                return
            end
            
            signalPlotXa = app.UIAxes.Position(1);
            signalPlotYa = app.UIAxes.Position(2);
            signalPlotXb = app.UIAxes.Position(3) + signalPlotXa;
            signalPlotYb = app.UIAxes.Position(4) + signalPlotYa;
            currentPoint = event.Source.CurrentPoint;
            
            if (currentPoint(1) > signalPlotXa && currentPoint(1) < signalPlotXb) && (currentPoint(2) > signalPlotYa && currentPoint(2) < signalPlotYb)
                pt = app.UIAxes.CurrentPoint(1,1:2);
                switch app.selectionState
                    case 0
                        if app.fragmentSelected == 0
                            positionLine(app,pt)
                        end
                    case 1
                        selectStart(app,pt)
                    case 2
                        selectEnd(app,pt)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.CallingApp)
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            for i = 1:length(app.Panel.Children)
                app.resizeelement(app.Panel.Children(i));
            end
            
            for i = 1:length(app.Panel_2.Children)
                app.resizeelement(app.Panel_2.Children(i));
            end
            
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            url = app.help_url;
            ruta = 'C:/Program Files/Aholab/SignalVisualizer/application/';
            url = strcat(ruta,url);
            
            createHelp(app.CallingApp,url);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Tiempo (t)')
            ylabel(app.UIAxes, '')
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Enable = 'off';
            app.playButton.Text = '';
            app.AbrirficheroButton = uibutton(app.UIFigure, 'push');
            app.AbrirficheroButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirficheroButtonPushed, true);
            app.AbrirficheroButton.Text = 'Abrir fichero';
            app.Panel = uipanel(app.UIFigure);
            app.DuracinLabel = uilabel(app.Panel);
            app.DuracinLabel.Text = 'Duración';
            app.fsField = uieditfield(app.Panel, 'numeric');
            app.fsField.ValueDisplayFormat = '%.0f';
            app.fsField.Editable = 'off';
            app.fsLabel = uilabel(app.Panel);
            app.fsLabel.Text = 'Fs';
            app.durationField = uieditfield(app.Panel, 'text');
            app.durationField.Editable = 'off';
            app.durationField.Value = '00:00.000';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Enable = 'off';
            app.stopButton.Text = '';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Enable = 'off';
            app.CargarButton.Text = 'Cargar';
            app.Panel_2 = uipanel(app.UIFigure);
            app.fragmentoCheckBox = uicheckbox(app.Panel_2);
            app.fragmentoCheckBox.ValueChangedFcn = createCallbackFcn(app, @fragmentoCheckBoxValueChanged, true);
            app.fragmentoCheckBox.Enable = 'off';
            app.fragmentoCheckBox.Text = '';
            app.SeleccionarfragmentoLabel = uilabel(app.Panel_2);
            app.SeleccionarfragmentoLabel.Text = 'Seleccionar fragmento';
            app.InicioLabel = uilabel(app.Panel_2);
            app.InicioLabel.Text = 'Inicio';
            app.FinLabel = uilabel(app.Panel_2);
            app.FinLabel.Text = 'Fin';
            app.inicioMEditField = uieditfield(app.Panel_2, 'text');
            app.inicioMEditField.ValueChangedFcn = createCallbackFcn(app, @inicioMEditFieldValueChanged, true);
            app.inicioMEditField.Enable = 'off';
            app.inicioMEditField.Value = '00';
            app.inicioSEditField = uieditfield(app.Panel_2, 'text');
            app.inicioSEditField.ValueChangedFcn = createCallbackFcn(app, @inicioSEditFieldValueChanged, true);
            app.inicioSEditField.Enable = 'off';
            app.inicioSEditField.Value = '00.000';
            app.mLabel = uilabel(app.Panel_2);
            app.mLabel.Text = 'm';
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = 's';
            app.finMEditField = uieditfield(app.Panel_2, 'text');
            app.finMEditField.ValueChangedFcn = createCallbackFcn(app, @finMEditFieldValueChanged, true);
            app.finMEditField.Enable = 'off';
            app.finMEditField.Value = '00';
            app.finSEditField = uieditfield(app.Panel_2, 'text');
            app.finSEditField.ValueChangedFcn = createCallbackFcn(app, @finSEditFieldValueChanged, true);
            app.finSEditField.Enable = 'off';
            app.finSEditField.Value = '00.000';
            app.mLabel_2 = uilabel(app.Panel_2);
            app.mLabel_2.Text = 'm';
            app.sLabel_2 = uilabel(app.Panel_2);
            app.sLabel_2.Text = 's';
            app.CambiarseleccinButton = uibutton(app.Panel_2, 'push');
            app.CambiarseleccinButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarseleccinButtonPushed, true);
            app.CambiarseleccinButton.Enable = 'off';
            app.CambiarseleccinButton.Text = 'Cambiar selección';
            app.inicioOkButton = uibutton(app.Panel_2, 'push');
            app.inicioOkButton.ButtonPushedFcn = createCallbackFcn(app, @inicioOkButtonPushed, true);
            app.inicioOkButton.Enable = 'off';
            app.inicioOkButton.Text = 'Ok';
            app.finOkButton = uibutton(app.Panel_2, 'push');
            app.finOkButton.ButtonPushedFcn = createCallbackFcn(app, @finOkButtonPushed, true);
            app.finOkButton.Enable = 'off';
            app.finOkButton.Text = 'Ok';
            app.infoLabelInicio = uilabel(app.UIFigure);
            app.infoLabelInicio.Visible = 'off';
            app.infoLabelInicio.Text = 'Haz clic sobre la pista de audio o introduce el valor de inicio';
            app.infoLabelFin = uilabel(app.UIFigure);
            app.infoLabelFin.Visible = 'off';
            app.infoLabelFin.Text = 'Haz clic sobre la pista de audio o introduce el valor de fin';
            app.CargarficherodeaudioLabel = uilabel(app.UIFigure);
            app.CargarficherodeaudioLabel.Text = 'Cargar fichero de audio';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Enable = 'off';
            app.infoButton.Visible = 'off';
            app.infoButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end