classdef noise_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        AmplitudmxSlider     matlab.ui.control.Slider
        TipoderuidoDropDown  matlab.ui.control.DropDown
        NuevamuestraButton   matlab.ui.control.Button
        GenerarButton        matlab.ui.control.Button
        GenerarruidoLabel    matlab.ui.control.Label
        AmplitudmxLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        t % Array de tiempo
        current_data % Muestra actual
        fs % Frecuencia de muestreo
        type % Tipo de ruido actual
        amp % Amplitud
        CallingApp % App principal
    end
    
    methods (Access = private)
        
        % Genera un array de fs muestras de ruido blanco
        % Con valores entre -1 y 1
        function wn = whiteNoise(~,fs)
            wn = randn(1,fs);
            wn = wn./max(abs(wn));
        end
        
        % Lo mismo, con ruido rosa
        function pn = pinkNoise(~,fs)
            pn = pinknoise(fs)';
            pn = pn./max(abs(pn));
        end
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 640;
            originalh = 480;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
        end
        
        function resizeelement(~,element)
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalw = element.Position(3);
            originalh = element.Position(4);
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            newhpos = round(originalhpos*screenw/1280);
            newvpos = round(originalvpos*screenh/1024);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(screenw*screenh)/(1280*1024));
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %    Red Noise Generation with MATLAB Implementation   %
        %                                                      %
        % Author: M.Sc. Eng. Hristo Zhivomirov        07/31/13 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function y = redNoise(~,n)
            % m - number of matrix rows
            % n - number of matrix columns
            % y - matrix with red (Brownian) noise samples
            %     with mu = 0 and sigma = 1 (columnwise)
            % The function generates a matrix of red (Brownian) noise samples
            % (columnwise). In terms of power at a constant bandwidth, red
            % noise falls off at 6 dB/oct, i.e. 20 dB/dec.
            % difine the length of the noise vector and ensure
            % that M is even, this will simplify the processing
            m = 1; n = round(n); N = m*n;
            if rem(N, 2)
                M = N+1;
            else
                M = N;
            end
            % generate white noise sequence
            x = randn(1, M);
            % FFT
            X = fft(x);
            % prepare a vector with frequency indexes
            NumUniquePts = M/2 + 1;     % number of the unique fft points
            k = 1:NumUniquePts;         % vector with frequency indexes
            % manipulate the left half of the spectrum so the PSD
            % is proportional to the frequency by a factor of 1/(f^2),
            % i.e. the amplitudes are proportional to 1/f
            X = X(1:NumUniquePts);
            X = X./k;
            % prepare the right half of the spectrum - a conjugate copy of the left
            % one except the DC component and the Nyquist component - they are unique,
            % and reconstruct the whole spectrum
            X = [X conj(X(end-1:-1:2))];
            % IFFT
            y = real(ifft(X));
            % ensure that the length of y is N
            y = y(1, 1:N);
            % form the noise matrix and ensure unity standard
            % deviation and zero mean value (columnwise)
            y = reshape(y, [m, n]);
            y = bsxfun(@minus, y, mean(y));
            y = bsxfun(@rdivide, y, std(y));
            
            y = y./max(abs(y));
        end
        
        function translate(app,language)
            load('language.mat','noise');
            
            app.UIAxes.YLabel.String = noise.amplitude{language};
            app.UIAxes.XLabel.String = noise.time{language};
            
            app.TipoderuidoDropDown.Items = noise.dropdown{language};
            
            app.NuevamuestraButton.Text = noise.new_sample{language};
            app.GenerarButton.Text = noise.generate{language};
            
            app.AmplitudmxLabel.Text = noise.max_amplitude{language};
            app.GenerarruidoLabel.Text = noise.title{language};
            
            clear('noise')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, language)
                app.CallingApp = mainApp;
                
                app.resizeWindow();
                for i = 1:length(app.UIFigure.Children)
                    app.resizeelement(app.UIFigure.Children(i));
                end
                
                app.translate(language);
                
                % Inicializa las variables
                app.fs = 48000;
                app.t = linspace(0,1,app.fs);
                app.type = 1;
                app.amp = 1;
                
                % Genera muestra predeterminada y la dibuja
                app.current_data = app.whiteNoise(app.fs);
                
                plot(app.UIAxes,app.t,app.current_data)
        end

        % Value changed function: TipoderuidoDropDown
        function TipoderuidoDropDownValueChanged(app, event)
                % Cambia el tipo de ruido y crea una nueva muestra
                app.type = find(contains(app.TipoderuidoDropDown.Items,app.TipoderuidoDropDown.Value));
                
                switch app.type
                    case 1
                        app.current_data = app.amp*app.whiteNoise(app.fs);
                    case 2
                        app.current_data = app.amp*app.pinkNoise(app.fs);
                    case 3
                        app.current_data = app.amp*app.redNoise(app.fs);
                end
                
                plot(app.UIAxes,app.t,app.current_data)
        end

        % Value changed function: AmplitudmxSlider
        function AmplitudmxSliderValueChanged(app, event)
                % Cambia la amplitud seleccionada y modifica la amplitud de
                % la muestra actual
                app.amp = round(app.AmplitudmxSlider.Value,2);
                
                app.current_data = app.current_data.*(app.amp/max(abs(app.current_data)));
                
                plot(app.UIAxes,app.t,app.current_data)
        end

        % Button pushed function: NuevamuestraButton
        function NuevamuestraButtonPushed(app, event)
                % Genera nueva muestra con los valores seleccionados
                
                switch app.type
                    case 1
                        app.current_data = app.amp*app.whiteNoise(app.fs);
                    case 2
                        app.current_data = app.amp*app.pinkNoise(app.fs);
                    case 3
                        app.current_data = app.amp*app.redNoise(app.fs);
                end
                
                plot(app.UIAxes,app.t,app.current_data)
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
                % Envía la señal a la aplicación principal y cierra
                
                updatePlot(app.CallingApp,app.current_data,app.t);
                
                % Habilita los menús de la aplicación principal
                app.CallingApp.SignalVisualizerMenu.Enable = 'on';
                app.CallingApp.GenerarMenu.Enable = 'on';
                
                delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                % Al cerrar la ventana pulsando el botón
                
                % Habilita los menús de la aplicación principal
                app.CallingApp.SignalVisualizerMenu.Enable = 'on';
                app.CallingApp.GenerarMenu.Enable = 'on';
                
                % Habilita el botón play sólo si ya hay una señal previamente
                % cargada
                if isempty(app.CallingApp.signal) == 0
                    app.CallingApp.PlayButton.Enable = 'on';
                end
                
                delete(app)
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.AmplitudmxSlider = uislider(app.UIFigure);
            app.AmplitudmxSlider.Limits = [0 1];
            app.AmplitudmxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmplitudmxSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudmxSliderValueChanged, true);
            app.AmplitudmxSlider.Value = 1;
            app.TipoderuidoDropDown = uidropdown(app.UIFigure);
            app.TipoderuidoDropDown.Items = {'Ruido blanco', 'Ruido rosa', 'Ruido marrón'};
            app.TipoderuidoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoderuidoDropDownValueChanged, true);
            app.TipoderuidoDropDown.Value = 'Ruido blanco';
            app.NuevamuestraButton = uibutton(app.UIFigure, 'push');
            app.NuevamuestraButton.ButtonPushedFcn = createCallbackFcn(app, @NuevamuestraButtonPushed, true);
            app.NuevamuestraButton.Text = 'Nueva muestra';
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.GenerarruidoLabel = uilabel(app.UIFigure);
            app.GenerarruidoLabel.Text = 'Generar ruido';
            app.AmplitudmxLabel = uilabel(app.UIFigure);
            app.AmplitudmxLabel.Text = 'Amplitud máx.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end