classdef tone_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        axes1        matlab.ui.control.UIAxes
        amp_slider   matlab.ui.control.Slider
        frec_slider  matlab.ui.control.Slider
        fase_slider  matlab.ui.control.Slider
        generar_btm  matlab.ui.control.Button
        text2        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        amp_box      matlab.ui.control.EditField
        frec_box     matlab.ui.control.EditField
        fase_box     matlab.ui.control.EditField
        text6        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        expresion    matlab.ui.control.Label
        text10       matlab.ui.control.Label
        infoButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        amp % Amplitud del seno
        frec % Frecuencia
        theta % Desfase
        t % Array de tiempo
        current_data % Señal actual
        CallingApp % Aplicación principal
        fs % Frecuencia de muestreo
        windowOriginalWidth = 651; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 672; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        numerical_warning = 'El valor introducido debe ser numérico';
        phase_warning = 'El valor debe estar entre -1 y 1';
        frec_warning = 'La frecuencia debe estar entre 0 y 20000';
        amp_warning = 'La amplitud debe estar entre 0 y 1';
        window_warning = 'Cierra una ventana de visualización antes de abrir otra';
        visualizationName = 'Tono puro';
        
        plot_color
        bg_color
        
        help_url = 'Help files/es/Generar tono puro/Generartonopuro.html';
    end
    
    methods (Access = private)
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 651;
            originalh = 672;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.figure1.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.figure1.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
        end
        
        function translate(app,language)
            load('language.mat','tone')
            
            app.figure1.Name = tone.name{language};
            
            app.axes1.YLabel.String = tone.amplitude{language};
            app.axes1.XLabel.String = tone.time{language};
            
            app.generar_btm.Text = tone.generate{language};
            app.text2.Text = tone.amplitude{language};
            app.text3.Text = tone.frequency{language};
            app.text4.Text = tone.phase{language};
            
            app.uipanel1.Title = tone.expression{language};
            app.text10.Text = tone.title{language};
            
            app.numerical_warning = tone.numerical_warning{language};
            app.phase_warning = tone.phase_warning{language};
            app.frec_warning = tone.frec_warning{language};
            app.amp_warning = tone.amp_warning{language};
            app.window_warning = tone.window_warning{language};
            
            app.visualizationName = tone.visualizationName{language};
            
            app.help_url = tone.help_url{language};
            
            clear('tone')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainApp, user_data)
            
            app.CallingApp = mainApp;
            
            app.translate(user_data.lang_ind);
            app.plot_color = user_data.plot_color;
            app.bg_color = user_data.bg_color;
            
            % Inicialización de los valores
            app.fs = 48000;
            
            app.t = linspace(0,1,app.fs);
            app.amp = 0.5;
            app.amp_box.Value = num2str(app.amp);
            
            app.frec = 2;
            app.frec_box.Value = num2str(app.frec);
            
            app.theta = 0;
            app.fase_box.Value = num2str(app.theta);
            
            % Representación de la señal predeterminada
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            app.axes1.Color = app.bg_color;
            ylim(app.axes1,[-1,1])
            app.axes1.XLabel.String = xlab;
            app.axes1.YLabel.String = ylab;
            
        end

        % Value changed function: amp_box
        function amp_box_Callback(app, event)
            
            str = app.amp_box.Value;
            num = str2double(str);
            
            % Validar el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 1
                num = 0;
                warn = warndlg(app.amp_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza los elementos UI
            app.amp = round(num,2);
            app.amp_box.Value = num2str(app.amp);
            app.amp_slider.Value = app.amp;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
            
        end

        % Value changed function: amp_slider
        function amp_slider_Callback(app, event)
            
            % Redondea y actualiza la caja
            app.amp = round(app.amp_slider.Value,2);
            app.amp_box.Value = num2str(app.amp);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
            
        end

        % Value changed function: fase_box
        function fase_box_Callback(app, event)
            
            str = app.fase_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif abs(num) > 1
                num = 0;
                warn = warndlg(app.phase_warning);
                uiwait(warn);
            end
            
            % Redondea y actualiza la UI
            app.theta = round(num,1);
            app.fase_box.Value = num2str(app.theta);
            app.fase_slider.Value = app.theta;
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
            
        end

        % Value changed function: fase_slider
        function fase_slider_Callback(app, event)
            % Redondea y actualiza la caja
            app.theta = round(app.fase_slider.Value,1);
            app.fase_box.Value = num2str(app.theta);
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
            
        end

        % Value changed function: frec_box
        function frec_box_Callback(app, event)
            
            str = app.frec_box.Value;
            num = str2double(str);
            
            % Valida el valor introducido
            if isempty(num) || isnan(num)
                num = 0;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0 || num > 20000
                num = 0;
                warn = warndlg(app.frec_warning);
                uiwait(warn);
            end
            
            %Redondea y actualiza la UI
            app.frec = round(num);
            app.frec_box.Value = num2str(app.frec);
            app.frec_slider.Value = app.frec;
            
            % Actualiza la señal y la representa
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
        end

        % Value changed function: frec_slider
        function frec_slider_Callback(app, event)
            % Redondea y actualiza la UI
            app.frec = round(app.frec_slider.Value);
            app.frec_box.Value = num2str(app.frec,'%u');
            
            % Actualiza la señal y la dibuja
            app.current_data = app.amp*cos(2*pi*app.frec*app.t + pi*app.theta);
            
            app.expresion.Text = strcat(num2str(app.amp),'cos(2',char(hex2dec('3c0')),'·',num2str(app.frec),'·t+',num2str(app.theta),char(hex2dec('3c0')),')');
            xlab = app.axes1.XLabel.String;
            ylab = app.axes1.YLabel.String;
            plot(app.axes1,app.t,app.current_data,"Color",app.plot_color)
            xlabel(app.axes1,xlab)
            ylabel(app.axes1,ylab)
            ylim(app.axes1,[-1,1])
        end

        % Button pushed function: generar_btm
        function generar_btm_Callback(app, event)
            % hObject    handle to generar_btm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            success = createPlot(app.CallingApp,app.current_data,app.t,app.visualizationName);
            
            if success == 0
                warn = warndlg(app.window_warning);
                uiwait(warn);
                return
            end
            
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            % Al cerrar la ventana con el botón
            % Habilita los menús de la app principal
            if isvalid(app.CallingApp)
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            app.CallingApp.InputMenu.Enable = 'on';
            app.CallingApp.OpcionesMenu.Enable = 'on';
            end
            
            delete(app)
        end

        % Size changed function: figure1
        function figure1SizeChanged(app, event)
            
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            for i = 1:length(app.figure1.Children)
                app.resizeelement(app.figure1.Children(i));
            end
            
            app.resizeelement(app.expresion);
            
            position = app.figure1.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
            
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            url = app.help_url;
            ruta = 'C:/Program Files/Aholab/SignalVisualizer/application/';
            url = strcat(ruta,url);
            
            createHelp(app.CallingApp,url);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tone';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
            app.axes1 = uiaxes(app.figure1);
            xlabel(app.axes1, 'Amplitud')
            ylabel(app.axes1, 'Tiempo (s)')
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [-1 1];
            app.amp_slider = uislider(app.figure1);
            app.amp_slider.Limits = [0 1];
            app.amp_slider.MajorTicks = [];
            app.amp_slider.ValueChangedFcn = createCallbackFcn(app, @amp_slider_Callback, true);
            app.amp_slider.MinorTicks = [];
            app.amp_slider.Value = 0.5;
            app.frec_slider = uislider(app.figure1);
            app.frec_slider.Limits = [0 20000];
            app.frec_slider.MajorTicks = [];
            app.frec_slider.ValueChangedFcn = createCallbackFcn(app, @frec_slider_Callback, true);
            app.frec_slider.MinorTicks = [];
            app.frec_slider.Value = 2;
            app.fase_slider = uislider(app.figure1);
            app.fase_slider.Limits = [-1 1];
            app.fase_slider.MajorTicks = [];
            app.fase_slider.ValueChangedFcn = createCallbackFcn(app, @fase_slider_Callback, true);
            app.fase_slider.MinorTicks = [];
            app.generar_btm = uibutton(app.figure1, 'push');
            app.generar_btm.ButtonPushedFcn = createCallbackFcn(app, @generar_btm_Callback, true);
            app.generar_btm.Text = 'Generar';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Amplitud';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Frecuencia';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Fase';
            app.amp_box = uieditfield(app.figure1, 'text');
            app.amp_box.ValueChangedFcn = createCallbackFcn(app, @amp_box_Callback, true);
            app.amp_box.Value = 'Edit Text';
            app.frec_box = uieditfield(app.figure1, 'text');
            app.frec_box.ValueChangedFcn = createCallbackFcn(app, @frec_box_Callback, true);
            app.frec_box.Value = 'Edit Text';
            app.fase_box = uieditfield(app.figure1, 'text');
            app.fase_box.ValueChangedFcn = createCallbackFcn(app, @fase_box_Callback, true);
            app.fase_box.Value = 'Edit Text';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Hz';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'π rad';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Expresión';
            app.expresion = uilabel(app.uipanel1);
            app.expresion.Text = '';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Generar tono puro';
            app.infoButton = uibutton(app.figure1, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Enable = 'off';
            app.infoButton.Visible = 'off';
            app.infoButton.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tone_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end