classdef visualization_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VentanaDropDown                matlab.ui.control.DropDown
        nfftDropDown                   matlab.ui.control.DropDown
        TamaodeventanaEditField        matlab.ui.control.NumericEditField
        VentanaLabel                   matlab.ui.control.Label
        TamaodeventanaLabel            matlab.ui.control.Label
        nfftLabel                      matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        PlusxButton1                   matlab.ui.control.Button
        MinusxButton1                  matlab.ui.control.Button
        PlusyButton1                   matlab.ui.control.Button
        MinusyButton1                  matlab.ui.control.Button
        UpButton1                      matlab.ui.control.Button
        DownButton1                    matlab.ui.control.Button
        RightButton1                   matlab.ui.control.Button
        LeftButton1                    matlab.ui.control.Button
        ResetButton1                   matlab.ui.control.Button
        exportButton1                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        PlusxButton2                   matlab.ui.control.Button
        MinusxButton2                  matlab.ui.control.Button
        PlusyButton2                   matlab.ui.control.Button
        MinusyButton2                  matlab.ui.control.Button
        UpButton2                      matlab.ui.control.Button
        DownButton2                    matlab.ui.control.Button
        RightButton2                   matlab.ui.control.Button
        LeftButton2                    matlab.ui.control.Button
        ResetButton2                   matlab.ui.control.Button
        exportButton2                  matlab.ui.control.Button
        infoButton                     matlab.ui.control.Button
        Panel_Reproduccion             matlab.ui.container.Panel
        stopButton                     matlab.ui.control.Button
        playSegmentButton              matlab.ui.control.Button
        playButton                     matlab.ui.control.Button
        Panel_layout_3                 matlab.ui.container.Panel
        middle_layout3                 matlab.ui.control.Button
        up_layout3                     matlab.ui.control.Button
        restore_layout3                matlab.ui.control.Button
        down_layout3                   matlab.ui.control.Button
        Panel_layout_2                 matlab.ui.container.Panel
        down_layout2                   matlab.ui.control.Button
        up_layout2                     matlab.ui.control.Button
        restore_layout2                matlab.ui.control.Button
        fsField                        matlab.ui.control.NumericEditField
        fsLabel                        matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        ExportarimagenMenu_UIAxes      matlab.ui.container.Menu
        GuardarcomoMenu                matlab.ui.container.Menu
        CopiarcomomapadebitsMenu       matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu  matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        EscaladelejeYMenu_UIAxes2      matlab.ui.container.Menu
        LinealMenu_2                   matlab.ui.container.Menu
        LogartmicaMenu_2               matlab.ui.container.Menu
        ExportarimagenMenu_UIAxes2     matlab.ui.container.Menu
        GuardarcomoMenu_2              matlab.ui.container.Menu
        CopiarcomomapadebitsMenu_2     matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu_2  matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        ExportarimagenMenu_UIAxes3     matlab.ui.container.Menu
        GuardarcomoMenu_3              matlab.ui.container.Menu
        CopiarcomomapadebitsMenu_3     matlab.ui.container.Menu
        CopiarcomoimagenvectorialMenu_3  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        windowNumber % Número de plotApp asignado
        
        time % Description
        fs = 48000; % Description
        CallingApp % Description
        mode % 0: ft 1: fft
        layout = 0 % 0: dos plots 1: tres plots
        wSize % Tamaño de ventana del SFTF
        nfft % Description
        overlap = 0.66; % Overlap de ventanas
        wType % Tipo de ventada
        center = 0;% Situación del puntero sobre la señal
        center2 = 0; % Situación del puntero sobre el espectro
        startPlaying = 0;
        player
        
        maxWindow = 1; % Máximo tamaño de la ventana (en segundos)
        
        lockedButtons = cell(20); % Para la función lock buttoms, guarda qué botones estaban bloqueados
        
        yScale2 = 'log';
        
        windowOriginalWidth = 720;%634; % Anchura de la ventana antes de redimensionar
        windowOriginalHeight = 554; % Altura de la ventana antes de redimensionar
        windowResized = 0; % Si ya se ha ejecutado la función windowResized
        % Dimensiones originales de los ejes en los diferentes layout
        % Layout con dos ejes
        axes1_layout2 = [10,305,631,220];
        axes2_layout2 = [10,82,631,220];
        % Layout con tres ejes
        axes1_layout3 = [10,376,631,146.67];
        axes2_layout3 = [10,229,631,146.67];
        axes3_layout3 = [10,82,631,146.67];
        
        % Layout plot ampliado
        mainPlot_size = [10,82,631,443];
        
        zoomxFactor1 = 1;
        zoomyFactor1 = 1;
        zoomxFactor2 = 1;
        zoomyFactor2 = 1;
        yLim1
        xLim2
        yLim2
        xLim3
        yLim3
        
        numerical_warning = 'El valor introducido debe ser un número'; % Description
        even_warning = 'El valor introducido debe ser par'; % Description
        wsize_warning = 'El valor introducido debe estar entre 0.001 y 5 segundos.';
        wsize2_warning = 'El valor introducido no puede ser mayor que la duración de la señal.';
        window_warning = 'La ventana no puede situarse fuera de la señal'; % Description
        ft_title = 'Espectro';
        stft_title = 'Espectro';
        spectrogram_title = 'Espectrograma';
        export_signal = 'Exportar datos de forma de onda';
        export_spectrum = 'Exportar datos de espectro';
        freq_label = 'Frecuencia (Hz)';
        time_label = 'Tiempo (s)';
        amp_label = 'Amplitud';
        amp_spectrum_label = 'Amplitud (dB)';
        
        plot_color;
        spectrogram_colormap;
        text_color;
        window_color;
        
        % Esta variable se usa para saber si calcular el zoom o no
        state = 0; % 0 = Inicio del programa // 1 = FT, STFT o STFT + espectrograma // 2 = Espectrograma
        
        help_url = 'Help files/es/Ventana de visualización/Ventanadevisualizacin.html';
    end
    
    properties (Access = public)
        signal = []; % Description
        spectrum = []; % Description
        freq = []; % Description
    end
    
    methods (Access = public)
        
        function updatePlot(app,signal,t,varargin)
            app.signal = signal;
            app.time = t;
            
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color);
            axtoolbar(app.UIAxes,{});
            
            % Ajusta el eje Y a la señal
            if ~isempty(varargin)
                if strcmp(varargin{1},'Positive')
                    app.UIAxes.YLim = [0,1];
                else % Si varargin(1) es 'SoundFile'
                    app.UIAxes.YLim = [-1,1];
                end
            else
                %                 if(max(abs(app.signal))>1)
                app.UIAxes.YLim = [-ceil(max(abs(app.signal))),ceil(max(abs(app.signal)))];
                %                 else
                %                     app.UIAxes.YLim = [-1,1];
                %                 end
            end
            
            app.UIAxes.XLim = [0,app.time(end)];
            app.yLim1 = app.UIAxes.YLim;
            
            % Ajusta el eje X a las frecuencias relevantes del fft
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            axtoolbar(app.UIAxes2,{});
            xa = find(app.spectrum > 10^-2,1);
            xb = find(app.spectrum > 10^-2,1,'last');
            
            if isempty(xa)
                xa = 0;
            else
                xa = app.freq(xa);
            end
            
            if isempty(xb)
                xb = app.fs/2;
            else
                xb = app.freq(xb);
            end
            
            if xa > 10
                xa = round(xa*0.8);
            else
                xa = 0;
            end
            
            if xb < 10
                xb = 10;
            elseif xb*1.2 < app.fs/2
                xb = xb*1.2;
            end
            
            app.xLim2 = [xa,xb];
            app.UIAxes2.XLim = app.xLim2;
            app.yLim2 = app.UIAxes2.YLim;
            
            app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
            if  app.zoomxFactor2 < 0.011
                app.PlusxButton2.Enable = 'off';
            else
                app.PlusxButton2.Enable = 'on';
            end
            
            if app.zoomxFactor2 < 0.91
                app.MinusxButton2.Enable = 'on';
            else
                app.MinusxButton2.Enable = 'off';
            end
            
            if(max(abs(app.signal)) > 1)
                signalAux = app.signal./max(abs(app.signal));
            else
                signalAux = app.signal;
            end
            
            app.state = 1;
            
            app.player = audioplayer(signalAux,app.fs);
            app.playButton.Enable = 'on';
            app.playSegmentButton.Enable = 'on';
            app.stopButton.Enable = 'on';
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 720;%634;
            originalh = 554;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(app,element)
            if strcmp(element.Type,'uicontextmenu') || strcmp(element.Type,'uimenu') % Al intentar redimensionar un UIContext Menu, salta un error
                return
            end
            
            warning('off') % Muestra warning pq no se puede cambiar height de los slider horizontales.
            % No merece la pena solucionar el problema.
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalElementWidth = element.Position(3);
            originalElementHeight = element.Position(4);
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(windowNewHeight/app.windowOriginalHeight));
            
            warning('on')
            
        end
        
        function translate(app,language)
            load('language.mat','visualization')
            
            %             app.UIFigure.Name = visualization.name{language};
            
            app.UIAxes.YLabel.String = visualization.amplitude{language};
            app.UIAxes.XLabel.String = visualization.time{language};
            app.time_label = visualization.time{language};
            app.UIAxes.Title.String = visualization.signal{language};
            
            app.amp_label = visualization.amplitude{language};
            app.amp_spectrum_label = strcat(app.amp_label,' (dB)');
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;            
            app.UIAxes2.XLabel.String = visualization.frequency{language};
            app.freq_label = visualization.frequency{language};
            app.ft_title = visualization.ft_title{language};
            app.stft_title = visualization.stft_title{language};
            app.spectrogram_title = visualization.spectrum_title{language};
            app.export_signal = visualization.export_signal{language};
            app.export_spectrum = visualization.export_spectrum{language};
            title(app.UIAxes2,app.ft_title);
            
            app.EscaladelejeYMenu_UIAxes2.Text = visualization.yscale{language};
            app.LinealMenu_2.Text = visualization.linear{language};
            app.LogartmicaMenu_2.Text = visualization.logarithmic{language};
            app.ExportarimagenMenu_UIAxes.Text = visualization.exportImage{language};
            app.ExportarimagenMenu_UIAxes2.Text = visualization.exportImage{language};
            app.ExportarimagenMenu_UIAxes3.Text = visualization.exportImage{language};
            app.GuardarcomoMenu.Text = visualization.saveAs{language};
            app.GuardarcomoMenu_2.Text = visualization.saveAs{language};
            app.GuardarcomoMenu_3.Text = visualization.saveAs{language};
            app.CopiarcomomapadebitsMenu.Text = visualization.copyBitmap{language};
            app.CopiarcomomapadebitsMenu_2.Text = visualization.copyBitmap{language};
            app.CopiarcomomapadebitsMenu_3.Text = visualization.copyBitmap{language};
            app.CopiarcomoimagenvectorialMenu.Text = visualization.copyVector{language};
            app.CopiarcomoimagenvectorialMenu_2.Text = visualization.copyVector{language};
            app.CopiarcomoimagenvectorialMenu_3.Text = visualization.copyVector{language};
            
            app.VentanaLabel.Text = visualization.window{language};
            app.VentanaDropDown.Items = visualization.window_type{language};
            app.TamaodeventanaLabel.Text = visualization.window_size{language};
            app.DropDown.Items = visualization.mode{language};
            
            app.even_warning = visualization.pair_warning{language};
            app.numerical_warning = visualization.numerical_warning{language};
            app.wsize_warning = visualization.wsize_warning{language};
            app.window_warning = visualization.window_warning{language};
            app.wsize2_warning = visualization.wsize2_warning{language};
            
            app.help_url = visualization.help_url{language};
            
            clear('visualization')
        end
        
        
        function plotCenterLine(app,pt,newClick)
            if pt(1) < 0
                pt(1) = mean(app.UIAxes.XLim);
            end
            
            cla(app.UIAxes);
            hold(app.UIAxes,'off')
            
            xa = pt(1) - (app.wSize-1)/(2*app.fs);
            xwidth = app.wSize/app.fs;
            if(xa < 0 || xa + xwidth > app.time(end))
                pt(1) = mean(app.UIAxes.XLim);
                %                 xa = pt(1) - (app.wSize-1)/(2*app.fs);
                %                 xwidth = app.wSize/app.fs;
                if newClick == 1
                    warn = warndlg(app.window_warning);
                    uiwait(warn);
                end
            end
            app.center = round(pt(1)*app.fs);
            %             ya = app.UIAxes.YLim(1);
            %             yheight = app.UIAxes.YLim(2) - app.UIAxes.YLim(1);
            %
            t_ventana = linspace((app.center-app.wSize/2)/app.fs,(app.center+app.wSize/2)/app.fs,app.wSize);
            
            app.startPlaying = round(app.center-app.wSize/2);
            
            switch app.wType
                case 1
                    window = rectwin(app.wSize)*max(abs(app.signal));
                case 2
                    window = hann(app.wSize)*max(abs(app.signal));
                case 3
                    window = hamming(app.wSize)*max(abs(app.signal));
                case 4
                    window = gausswin(app.wSize)*max(abs(app.signal));
                case 5
                    window = triang(app.wSize)*max(abs(app.signal));
                case 6
                    window = chebwin(app.wSize)*max(abs(app.signal));
                case 7
                    window = taylorwin(app.wSize)*max(abs(app.signal));
                case 8
                    window = bartlett(app.wSize)*max(abs(app.signal));
                case 9
                    window = kaiser(app.wSize)*max(abs(app.signal));
            end
            
            stem(app.UIAxes,t_ventana,window,'.','Color',app.window_color)
            
            hold(app.UIAxes,'on')
            
            stem(app.UIAxes,t_ventana,-window,'.','Color',app.window_color)
            
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,'.-',"Color",app.text_color)
            
            if app.mode == 4
                app.calculateSpectrogram();
                hold(app.UIAxes3,'on')
                app.calculateSTFT();
                app.UIAxes3.YLim = app.UIAxes2.XLim;
                plot(app.UIAxes3,[pt(1),pt(1)],app.UIAxes3.YLim,'.-',"Color",app.text_color);
            else
                app.calculateSTFT();
            end
        end
        
        function addDataTip(app,pt)
            ptx = round(pt(1)*app.fs);
            if ptx < 1
                hold(app.UIAxes,'off')
                plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
                return
            end
            
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.time,app.signal,"Color",app.plot_color)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,':',"Color",app.text_color)
            plot(app.UIAxes,app.UIAxes.XLim,[1 1]*app.signal(ptx),':',"Color",app.text_color)
            %             plot(app.UIAxes,pt(1),app.signal(ptx),'rx','MarkerSize',8)
            
            app.startPlaying = round(ptx);
            app.center = pt(1);
            
            if pt(1) < mean(app.UIAxes.XLim)
                text(app.UIAxes,app.UIAxes.XLim(2),app.UIAxes.YLim(1),sprintf(' x: %.2f\n y: %.2f',pt(1),app.signal(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom",'HorizontalAlignment',"right","Color",app.text_color)
            else
                text(app.UIAxes,app.UIAxes.XLim(1),app.UIAxes.YLim(1),sprintf(' x: %.2f\n y: %.2f',pt(1),app.signal(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","Color",app.text_color)
            end
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.95,sprintf('x: %.2f',pt(1)),'FontSize',14,"FontWeight","bold")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.75,sprintf('y: %.2f',app.signal(ptx)),'FontSize',14,"FontWeight","bold")
        end
        
        function addDataTipSpectrum(app,pt)
            if pt(1) < 0
                return
            end
            
            [~,ptx] = min(abs(app.freq-pt(1)));
            
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,[1,1]*app.freq(ptx),app.UIAxes2.YLim,':',"Color",app.text_color)
            plot(app.UIAxes2,app.UIAxes2.XLim,[1 1]*app.spectrum(ptx),':',"Color",app.text_color)
            %             plot(app.UIAxes,pt(1),app.signal(ptx),'rx','MarkerSize',8)
            app.center2 = pt(1);
            
            if app.freq(ptx) < mean(app.UIAxes2.XLim)
                text(app.UIAxes2,app.UIAxes2.XLim(2),app.UIAxes2.YLim(1),sprintf(' x: %.2f\n y: %.2f',app.freq(ptx),app.spectrum(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","HorizontalAlignment","right","Color",app.text_color)
            else
                text(app.UIAxes2,app.UIAxes2.XLim(1),app.UIAxes2.YLim(1),sprintf(' x: %.2f\n y: %.2f',app.freq(ptx),app.spectrum(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom","Color",app.text_color)
            end
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.95,sprintf('x: %.2f',pt(1)),'FontSize',14,"FontWeight","bold")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.75,sprintf('y: %.2f',app.signal(ptx)),'FontSize',14,"FontWeight","bold")
        end
        
        function calculateSTFT(app)
            
            lockButtoms(app,0) % Bloquear controles de usuario
            % botones
            
            x = app.signal(app.center - (app.wSize-1)/2:app.center + (app.wSize-1)/2);
            
            switch app.wType
                case 1
                    window = rectwin(app.wSize);
                case 2
                    window = hann(app.wSize);
                case 3
                    window = hamming(app.wSize);
                case 4
                    window = gausswin(app.wSize);
                case 5
                    window = triang(app.wSize);
                case 6
                    window = chebwin(app.wSize);
                case 7
                    window = taylorwin(app.wSize);
                case 8
                    window = bartlett(app.wSize);
                case 9
                    window = kaiser(app.wSize);
            end
            
            app.spectrum = pwelch(x,window,0,app.nfft);
            app.freq = linspace(0,app.fs/2,length(app.spectrum));
            
            cla(app.UIAxes2);
            %             colorbar(axes,"off");
            
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            app.UIAxes2.YScale = app.yScale2;
            
            switch app.state
                
                case 0
                    app.UIAxes2.XLim = app.xLim2;
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.xLim2 = app.UIAxes2.XLim;
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    app.center2 = 0;
                case 1
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                case 2
                    app.PlusxButton2.Enable = app.PlusyButton2.Enable;
                    app.MinusxButton2.Enable = app.MinusyButton2.Enable;
                    
                    ylim = app.UIAxes2.YLim;
                    
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    app.UIAxes2.XLim = ylim;
            end
            app.state = 1;
            
            app.UIAxes2.XLabel.String = app.freq_label;
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            title(app.UIAxes2,app.stft_title)
            
            lockButtoms(app,1) % Desbloquear controles de usuario
        end
        
        function calculateFT(app)
            lockButtoms(app,0) % Bloquear botones de usuario
            
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            cla(app.UIAxes2);
            colorbar(app.UIAxes2,"off");
            
            % Ajusta el eje X a las frecuencias relevantes del fft
            plot(app.UIAxes2,app.freq,app.spectrum,"Color",app.plot_color,'Marker','.');
            app.UIAxes2.YScale = app.yScale2;
            
            switch app.state
                
                case 0
                    app.UIAxes2.XLim = app.xLim2;
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.xLim2 = app.UIAxes2.XLim;
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                    
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    app.center2 = 0;
                    
                case 1
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                case 2
                    app.PlusxButton2.Enable = app.PlusyButton2.Enable;
                    app.MinusxButton2.Enable = app.MinusyButton2.Enable;
                    
                    ylim = app.UIAxes2.YLim;
                    
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    app.UIAxes2.XLim = ylim;
            end
            app.state = 1;
            
            app.UIAxes2.XLabel.String = app.freq_label;
            app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            title(app.UIAxes2,app.ft_title)
            
            lockButtoms(app,1) % Desbloquear controles de usuario
        end
        
        function calculateSpectrogram(app)
            
            lockButtoms(app,0) % Bloquea botones de usuario
            
            switch app.wType
                case 1
                    window = rectwin(app.wSize);
                case 2
                    window = hann(app.wSize);
                case 3
                    window = hamming(app.wSize);
                case 4
                    window = gausswin(app.wSize);
                case 5
                    window = triang(app.wSize);
                case 6
                    window = chebwin(app.wSize);
                case 7
                    window = taylorwin(app.wSize);
                case 8
                    window = bartlett(app.wSize);
                case 9
                    window = kaiser(app.wSize);
            end
            
            [s,f,t] = spectrogram(app.signal,window,round(app.overlap*app.wSize),app.nfft,app.fs);
            
            if app.mode == 4
                cla(app.UIAxes3);
                imagesc(app.UIAxes3,t,f,20*log10(abs(s)));
                set(app.UIAxes3,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                colormap(app.UIAxes3,app.spectrogram_colormap);
                
                axtoolbar(app.UIAxes3,{'export'}); % Parece que el ContextMenu3 no funciona
                
                app.UIAxes3.YLim = app.UIAxes2.XLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
                
                app.UIAxes3.YLabel.String = app.freq_label;
                app.UIAxes3.XLabel.String = app.time_label;
                app.UIAxes3.Title.String = app.spectrogram_title;
            else
                xlim = app.UIAxes2.XLim;
                
                app.UIAxes2.YScale = 'linear';
                
                cla(app.UIAxes2);
                
                if strcmp(app.yScale2,'log')
                    imagesc(app.UIAxes2,t,f,20*log10(abs(s)));
                else
                    imagesc(app.UIAxes2,t,f,abs(s));
                end
                
                set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                colormap(app.UIAxes2,app.spectrogram_colormap);
                
                switch app.state
                    
                    case 0
                        app.UIAxes2.YLim = app.xLim2;
                        app.UIAxes2.XLim = app.UIAxes.XLim;
                        
                        app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                        app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                        
                        app.zoomyFactor2 = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/app.freq(end);
                        if  app.zoomyFactor2 < 0.011
                            app.PlusyButton2.Enable = 'off';
                        else
                            app.PlusyButton2.Enable = 'on';
                        end
                        
                        if app.zoomyFactor2 < 0.91
                            app.MinusyButton2.Enable = 'on';
                        else
                            app.MinusyButton2.Enable = 'off';
                        end
                        
                    case 1
                        app.PlusyButton2.Enable = app.PlusxButton2.Enable;
                        app.MinusyButton2.Enable = app.MinusxButton2.Enable;
                        
                        app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                        app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                        
                        app.UIAxes2.XLim = app.UIAxes.XLim;
                        app.UIAxes2.YLim = xlim;
                end
                app.state = 2;
                
                app.UIAxes2.YLabel.String = app.freq_label;
                app.UIAxes2.XLabel.String = app.time_label;
                app.UIAxes2.Title.String = app.spectrogram_title;
                
            end
            
            lockButtoms(app,1) % Desbloquea botones de usuario
            
        end
        
        function layout3(app) % Cambiar a vista de tres ejes
            % Posición de los ejes para layout de tres plots
            app.UIAxes.Position = app.axes1_layout3;
            app.UIAxes2.Position = app.axes2_layout3;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los tres plots
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'on';
            
            % Hacer visibles ambos paneles de cambio de layout (Uno de ellos podría estar oculto)
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
            
            % Mostrar botones del layout de tres plots
            app.Panel_layout_2.Visible = 'off';
            app.Panel_layout_3.Visible = 'on';
            
            app.layout = 1;
        end
        
        function layout2(app) % Cambiar a vista de dos ejes
            % Posición de los ejes para layout de dos plot
            app.UIAxes.Position = app.axes1_layout2;
            app.UIAxes2.Position = app.axes2_layout2;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los ejes 1 y 2, y ocultar el 3
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            
            % Hacer visibles ambos paneles de cambio de layout (Uno de ellos podría estar oculto)
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
            
            % Mostrar botones correspondientes al layout de 2 plot
            app.Panel_layout_3.Visible = 'off';
            app.Panel_layout_2.Visible = 'on';
            
            app.layout = 0;
        end
        
        function resizeLayout(app)
            originalhpos = [app.axes1_layout2(1),app.axes1_layout3(1),app.axes2_layout2(1),app.axes2_layout3(1),app.axes3_layout3(1),app.mainPlot_size(1)];
            originalvpos = [app.axes1_layout2(2),app.axes1_layout3(2),app.axes2_layout2(2),app.axes2_layout3(2),app.axes3_layout3(2),app.mainPlot_size(2)];
            originalElementWidth = [app.axes1_layout2(3),app.axes1_layout3(3),app.axes2_layout2(3),app.axes2_layout3(3),app.axes3_layout3(3),app.mainPlot_size(3)];
            originalElementHeight = [app.axes1_layout2(4),app.axes1_layout3(4),app.axes2_layout2(4),app.axes2_layout3(4),app.axes3_layout3(4),app.mainPlot_size(4)];
            
            % Dimensiones de la nueva ventana
            figurePosition = app.UIFigure.Position;
            windowNewWidth = figurePosition(3);
            windowNewHeight = figurePosition(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalElementWidth*windowNewWidth/app.windowOriginalWidth);
            newh = round(originalElementHeight*windowNewHeight/app.windowOriginalHeight);
            
            newhpos = round(originalhpos*windowNewWidth/app.windowOriginalWidth);
            newvpos = round(originalvpos*windowNewHeight/app.windowOriginalHeight);
            
            app.axes1_layout2 = [newhpos(1) newvpos(1) neww(1) newh(1)];
            app.axes1_layout3 = [newhpos(2) newvpos(2) neww(2) newh(2)];
            app.axes2_layout2 = [newhpos(3) newvpos(3) neww(3) newh(3)];
            app.axes2_layout3 = [newhpos(4) newvpos(4) neww(4) newh(4)];
            app.axes3_layout3 = [newhpos(5) newvpos(5) neww(5) newh(5)];
            app.mainPlot_size = [newhpos(6) newvpos(6) neww(6) newh(6)];
        end
        
        
        function lockButtoms(app,lock)
            % Función para bloquear o desbloquear los botones de usuario
            % mientras se calculan el espectro o el espectrograma
            
            if lock == 0 % Bloquear todos los botones
                modo = 'off';
                
                % Guarda el estado de los botones y los bloquea
                lockedButtomIndex = 1;
                
                for i = 1:length(app.Panel.Children)
                    app.lockedButtons{lockedButtomIndex} = app.Panel.Children(i).Enable;
                    app.Panel.Children(i).Enable = 'off';
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
                
                for i = 1:length(app.Panel_2.Children)
                    app.lockedButtons{lockedButtomIndex} = app.Panel_2.Children(i).Enable;
                    app.Panel_2.Children(i).Enable = 'off';
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
            else
                modo = 'on';
                
                % Restaura el estado de los botones
                lockedButtomIndex = 1;
                
                for i = 1:length(app.Panel.Children)
                    app.Panel.Children(i).Enable = app.lockedButtons{lockedButtomIndex};
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
                
                for i = 1:length(app.Panel_2.Children)
                    app.Panel_2.Children(i).Enable = app.lockedButtons{lockedButtomIndex};
                    lockedButtomIndex = lockedButtomIndex + 1;
                end
            end
            
            % Bloquear/desbloquear los demás botones
            app.DropDown.Enable = modo;
            app.VentanaDropDown.Enable = modo;
            app.TamaodeventanaEditField.Enable = modo;
            app.nfftDropDown.Enable = modo;


            for i = 1:length(app.Panel_Reproduccion.Children)
                app.Panel_Reproduccion.Children(i).Enable = modo;
            end
            
            for i = 1:length(app.Panel_layout_2.Children)
                app.Panel_layout_2.Children(i).Enable = modo;
            end
            
            for i = 1:length(app.Panel_layout_3.Children)
                app.Panel_layout_3.Children(i).Enable = modo;
            end
            
            pause(0.001)     % Si no se pone, los botones no se bloquean (no sé por qué)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, user_data, signal, t, windowN, name, varargin)
            app.CallingApp = mainApp;
            
            app.translate(user_data.lang_ind);
            app.UIAxes.Color = user_data.bg_color;
            app.UIAxes2.Color = user_data.bg_color;
            app.plot_color = user_data.plot_color;
            app.spectrogram_colormap = user_data.spectrogram_colormap;
            app.window_color = user_data.selection_color;
            bg_color = user_data.bg_color;
            
            % Selecciona el color del texto y de la línea de selección para
            % que haya buen contraste
            if (bg_color(1)*0.299 + bg_color(2)*0.587 + bg_color(3)*0.114) < 0.73
                app.text_color = [1,1,1];
            else
                app.text_color = [0,0,0];
            end
            
            app.fs = 48000; % La frecuencia de muestreo es 48 kHz
            
            if ~isempty(varargin)
                if strcmp(varargin{1},'SoundFile')
                    app.fs = varargin{2}; % Si se está cargando un fichero de sonido, se coge su fs
                end
            end
            
            app.fsField.Value = app.fs;
            
            app.UIFigure.Name = name;
            app.mode = 1;
            app.wType = 1;
            app.wSize = app.TamaodeventanaEditField.Value;
            
            app.windowNumber = windowN;
            
            %% Calcular posibles valores de NFFT
            nfft1 = max(256,2^nextpow2(app.wSize));
            
            memoryAux = memory; % Array con datos sobre memoria
            maxElements = floor(memoryAux.MaxPossibleArrayBytes/16); % Nº máximo de elementos de un array de double complex
            segmentN = ceil(length(signal)/(app.wSize*(1-app.overlap))); % Número de puntos en los que se calcula al hacer el barrido de ventana
            maxNfft = 0.5*maxElements/segmentN; % Se permite el 50% del máximo posible
            
            i = 1;
            arrayNFFT = cell(20);
            while nfft1 < maxNfft
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            arrayNFFT = arrayNFFT(1:i - 1);
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});
            
            app.updatePlot(signal,t,varargin{:})
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            play(app.player)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.CallingApp)
                app.CallingApp.plotApp_counter = app.CallingApp.plotApp_counter - 1;
                app.CallingApp.Panel.Children(length(app.CallingApp.Panel.Children) - app.windowNumber + 1).Enable = 'off'; % Desactiva el botón asignado a esta ventana
                app.CallingApp.Panel.Children(length(app.CallingApp.Panel.Children) - app.windowNumber + 1).Text = num2str(app.windowNumber);
            end
            
            delete(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Si se ha hecho clic con el botón derecho, sale. (Alt -> Clic dcho. Open -> Doble clic dcho)
            if strcmp(event.Source.SelectionType,'alt') || strcmp(event.Source.SelectionType,'open')
                return
            end
            
            signalPlotXa = app.UIAxes.Position(1);
            signalPlotYa = app.UIAxes.Position(2);
            signalPlotXb = app.UIAxes.Position(3) + signalPlotXa;
            signalPlotYb = app.UIAxes.Position(4) + signalPlotYa;
            currentPoint = event.Source.CurrentPoint;
            
            if (currentPoint(1) > signalPlotXa && currentPoint(1) < signalPlotXb) && (currentPoint(2) > signalPlotYa && currentPoint(2) < signalPlotYb)
                pt = app.UIAxes.CurrentPoint(1,1:2);
                if app.mode == 2 || app.mode == 4 % Modo STFT o STFT + espectrograma
                    app.plotCenterLine(pt,1);
                else % Modo FFT
                    app.addDataTip(pt);
                end
                return
            end
            
            spectrumPlotXa = app.UIAxes2.Position(1);
            spectrumPlotYa = app.UIAxes2.Position(2);
            spectrumPlotXb = app.UIAxes2.Position(3) + spectrumPlotXa;
            spectrumPlotYb = app.UIAxes2.Position(4) + spectrumPlotYa;
            
            if (currentPoint(1) > spectrumPlotXa && currentPoint(1) < spectrumPlotXb) && (currentPoint(2) > spectrumPlotYa && currentPoint(2) < spectrumPlotYb) && app.mode ~= 3
                pt = app.UIAxes2.CurrentPoint(1,1:2);
                app.addDataTipSpectrum(pt);
            end
        end

        % Value changed function: TamaodeventanaEditField
        function TamaodeventanaEditFieldValueChanged(app, event)
            num = app.TamaodeventanaEditField.Value;
            
            % Valida el número introducido
            if isempty(num) || isnan(num)
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 0.001 || num > 1
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.wsize_warning);
                uiwait(warn);
            elseif num > app.time(end)
                num = min(app.time(end) * 0.2,1);
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(strcat(app.wsize2_warning,' (',num2str(round(app.time(end),4)),' s)'));
                uiwait(warn);
                %             elseif ~mod(num,2) && app.mode ~= 3
                %                 num = num + 1;
                %                 app.TamaodeventanaEditField.Value = num;
            end
            
            app.TamaodeventanaEditField.Value = round(num,3);
            % Dibuja la nueva ventana
            app.wSize = round(num*app.fs);
            app.wSize = app.wSize - mod(app.wSize + 1,2); % Si el número es par, se le suma 1
            
            %% Calcular posibles valores de NFFT
            
            memoryAux = memory; % Array con datos sobre memoria
            maxElements = floor(memoryAux.MaxPossibleArrayBytes/16); % Nº máximo de elementos de un array de double complex
            segmentN = ceil(length(app.signal)/(app.wSize*(1-app.overlap))); % Número de puntos en los que se calcula al hacer el barrido de ventana
            maxNfft = 0.5*maxElements/segmentN; % Se ajusta al 50% del máximo posible
            
            nfft1 = max(256,2^nextpow2(app.wSize));
            arrayNFFT = cell(20);
            arrayNFFT{1} = num2str(nfft1);
            nfft1 = nfft1*2;
            i = 2;
            while nfft1 < maxNfft
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            arrayNFFT = arrayNFFT(1:i - 1);
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});
            
            switch app.mode
                case {2,4}
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
                case 3
                    app.calculateSpectrogram();
            end
        end

        % Value changed function: nfftDropDown
        function nfftDropDownValueChanged(app, event)
            app.nfft = str2double(app.nfftDropDown.Value);
            
            switch app.mode
                case 2
                    app.calculateSTFT();
                case 3
                    app.calculateSpectrogram();
                case 4
                    app.calculateSTFT();
                    app.calculateSpectrogram();
            end
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            app.wType = find(contains(app.VentanaDropDown.Items,app.VentanaDropDown.Value));
            
            switch app.mode
                case {2,4}
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                case 3
                    app.calculateSpectrogram();
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.mode = find(contains(app.DropDown.Items,app.DropDown.Value));
            app.mode = app.mode(1); %Si hay varios valores, como en el caso de buscar "FT", se queda con el primero
            
            switch app.mode
                case 1
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    app.calculateFT();
                    app.addDataTip(app.UIAxes.CurrentPoint(1,1:2));
                    
                    app.VentanaLabel.Visible = 'off';
                    app.TamaodeventanaLabel.Visible = 'off';
                    app.nfftLabel.Visible = 'off';
                    app.VentanaDropDown.Visible = 'off';
                    app.VentanaDropDown.Enable = 'off';
                    app.TamaodeventanaEditField.Visible = 'off';
                    app.TamaodeventanaEditField.Enable = 'off';
                    app.nfftDropDown.Visible = 'off';
                    app.nfftDropDown.Enable = 'off';
                    
                case 2
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    app.TamaodeventanaEditField.Value = min(round(app.time(end) * 0.2,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[])
                    
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
                    
                case 3
                    if app.layout == 1
                        layout2(app)
                    end
                    
                    app.UIAxes2.YScale = 'linear'; % Devuelve el eje Y a escala lineal
                    
                    app.TamaodeventanaEditField.Value = min(round(app.time(end)*0.021,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[])
                    app.addDataTip(app.UIAxes.CurrentPoint(1,1:2));
                    
                    app.VentanaLabel.Visible = 'off';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
                    
                case 4
                    if app.layout == 0
                        layout3(app)
                    end
                    
                    app.TamaodeventanaEditField.Value = min(round(app.time(end) * 0.2,3),1);
                    TamaodeventanaEditFieldValueChanged(app,[])
                    
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            if app.windowResized == 0 % La ventana se ajusta a la resolución de la pantalla. Sólo al abrir la ventana
                app.resizeWindow();
                app.windowResized = 1;
            end
            
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            for i = 1:length(app.Panel.Children)
                app.resizeelement(app.Panel.Children(i));
            end
            
            for i = 1:length(app.Panel_2.Children)
                app.resizeelement(app.Panel_2.Children(i));
            end
            
            for i = 1:length(app.Panel_Reproduccion.Children)
                app.resizeelement(app.Panel_Reproduccion.Children(i));
            end
            
            for i = 1:length(app.Panel_layout_2.Children)
                app.resizeelement(app.Panel_layout_2.Children(i));
            end
            
            for i = 1:length(app.Panel_layout_3.Children)
                app.resizeelement(app.Panel_layout_3.Children(i));
            end
            
            resizeLayout(app);
            
            position = app.UIFigure.Position;
            app.windowOriginalWidth = position(3);
            app.windowOriginalHeight = position(4);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.player)
        end

        % Button pushed function: ResetButton1
        function ResetButton1Pushed(app, event)
            app.zoomxFactor1 = 1;
            app.zoomyFactor1 = 1;
            
            app.MinusxButton1.Enable = 'off';
            app.PlusxButton1.Enable = 'on';
            app.MinusyButton1.Enable = 'off';
            app.PlusyButton1.Enable = 'on';
            
            app.UIAxes.XLim = [0,app.time(end)];
            app.UIAxes.YLim = app.yLim1;
            
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.MinusxButton1.Enable = 'off';
                app.PlusxButton1.Enable = 'on';
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: PlusxButton1
        function PlusxButton1Pushed(app, event)
            maxLim = app.time(end);
            
            if app.center == 0
                centro = mean(app.UIAxes.XLim);
            else
                centro = app.center;
            end
            
            if app.zoomxFactor1 > 0.11
                app.zoomxFactor1 = app.zoomxFactor1 - 0.1;
            elseif app.zoomxFactor1 < 0.11
                app.zoomxFactor1 = app.zoomxFactor1 - 0.01;
            end
            
            if app.zoomxFactor1 < 0.011
                app.PlusxButton1.Enable = 'off';
            elseif app.zoomxFactor1 < 0.91
                app.MinusxButton1.Enable = 'on';
            end
            
            if centro-maxLim*app.zoomxFactor1/2 < 0
                app.UIAxes.XLim = [0,maxLim*app.zoomxFactor1];
            elseif centro+maxLim*app.zoomxFactor1/2 > app.time(end)
                app.UIAxes.XLim = [(app.time(end) - maxLim*app.zoomxFactor1),app.time(end)];
            else
                app.UIAxes.XLim = [centro-maxLim*app.zoomxFactor1/2,centro+maxLim*app.zoomxFactor1/2];
            end
            
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                app.MinusxButton2.Enable = app.MinusxButton1.Enable;
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: MinusxButton1
        function MinusxButton1Pushed(app, event)
            maxLim = app.time(end);
            
            if app.center == 0
                centro = mean(app.UIAxes.XLim);
            else
                centro = app.center/app.fs;
            end
            
            if app.zoomxFactor1 > 0.09
                app.zoomxFactor1 = app.zoomxFactor1 + 0.1;
            else
                app.zoomxFactor1 = app.zoomxFactor1 + 0.01;
            end
            
            if app.zoomxFactor1 > 0.91
                app.MinusxButton1.Enable = 'off';
            elseif app.zoomxFactor1 > 0.011
                app.PlusxButton1.Enable = 'on';
            end
            
            if centro-maxLim*app.zoomxFactor1/2 < 0
                centro = maxLim*app.zoomxFactor1/2;
            elseif centro+maxLim*app.zoomxFactor1/2 > app.time(end)
                centro = app.time(end) - maxLim*app.zoomxFactor1/2;
            end
            
            app.UIAxes.XLim = [centro-maxLim*app.zoomxFactor1/2,centro+maxLim*app.zoomxFactor1/2];
            
            if app.mode == 3
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                app.MinusxButton2.Enable = app.MinusxButton1.Enable;
            elseif app.mode == 4
                app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: RightButton1
        function RightButton1Pushed(app, event)
            if app.zoomxFactor1 > 0.09
                roundFactor = 3;
            else
                roundFactor = 4;
            end
            
            step = (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))/4;
            yLim = app.UIAxes.YLim;
            
            if round(app.UIAxes.XLim(2) + step,roundFactor) > app.time(end)
                app.UIAxes.XLim = [(app.time(end) - (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))),app.time(end)];
            else
                app.UIAxes.XLim = app.UIAxes.XLim + step;
            end
            
            app.UIAxes.YLim = yLim;
            
            if app.mode == 3
                yLim = app.UIAxes2.YLim;
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.UIAxes2.YLim = yLim;
            elseif app.mode == 4
                yLim = app.UIAxes3.YLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
                app.UIAxes3.YLim = yLim;
            end
        end

        % Button pushed function: LeftButton1
        function LeftButton1Pushed(app, event)
            
            if app.zoomxFactor1 > 0.09
                roundFactor = 3;
            else
                roundFactor = 4;
            end
            
            step = (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))/4;
            
            yLim = app.UIAxes.YLim;
            
            if round(app.UIAxes.XLim(1) - step,roundFactor) < 0
                app.UIAxes.XLim = [0, (app.UIAxes.XLim(2) - app.UIAxes.XLim(1))];
            else
                app.UIAxes.XLim = app.UIAxes.XLim - step;
            end
            
            app.UIAxes.YLim = yLim;
            
            if app.mode == 3
                yLim = app.UIAxes2.YLim;
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.UIAxes2.YLim = yLim;
            elseif app.mode == 4
                yLim = app.UIAxes3.YLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
                app.UIAxes3.YLim = yLim;
            end
        end

        % Button pushed function: PlusyButton1
        function PlusyButton1Pushed(app, event)
            maxLim = app.yLim1(2) - app.yLim1(1);
            centro = mean(app.UIAxes.YLim);
            
            app.zoomyFactor1 = app.zoomyFactor1 - 0.1;
            
            if app.zoomyFactor1 < 0.11
                app.PlusyButton1.Enable = 'off';
            elseif app.zoomyFactor1 < 0.91
                app.MinusyButton1.Enable = 'on';
            end
            
            app.UIAxes.YLim = [centro-maxLim*app.zoomyFactor1/2,centro+maxLim*app.zoomyFactor1/2];
        end

        % Button pushed function: MinusyButton1
        function MinusyButton1Pushed(app, event)
            maxLim = app.yLim1(2) - app.yLim1(1);
            centro = mean(app.UIAxes.YLim);
            
            app.zoomyFactor1 = app.zoomyFactor1 + 0.1;
            
            if app.zoomyFactor1 > 0.91
                app.MinusyButton1.Enable = 'off';
            elseif app.zoomyFactor1 > 0.11
                app.PlusyButton1.Enable = 'on';
            end
            
            app.UIAxes.YLim = [centro-maxLim*app.zoomyFactor1/2,centro+maxLim*app.zoomyFactor1/2];
        end

        % Button pushed function: UpButton1
        function UpButton1Pushed(app, event)
            step = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))/4;
            xLim = app.UIAxes.XLim;
            
            if round(app.UIAxes.YLim(2) + step,3) > app.yLim1(2)
                app.UIAxes.YLim = [app.yLim1(2) - (app.UIAxes.YLim(2) - app.UIAxes.YLim(1)),app.yLim1(2)];
            else
                app.UIAxes.YLim = app.UIAxes.YLim + step;
            end
            
            app.UIAxes.XLim = xLim;
        end

        % Button pushed function: DownButton1
        function DownButton1Pushed(app, event)
            step = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))/4;
            xLim = app.UIAxes.XLim;
            
            if round(app.UIAxes.YLim(1) - step,3) < app.yLim1(1)
                app.UIAxes.YLim = [app.yLim1(1),app.yLim1(1) + (app.UIAxes.YLim(2) - app.UIAxes.YLim(1))];
            else
                app.UIAxes.YLim = app.UIAxes.YLim - step;
            end
            
            app.UIAxes.XLim = xLim;
        end

        % Button pushed function: PlusxButton2
        function PlusxButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    maxLim = app.freq(end);
                    
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    else
                        centro = app.center2;
                    end
                    
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    if app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    end
                    
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        app.UIAxes2.XLim = [0, maxLim*app.zoomxFactor2];
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        app.UIAxes2.XLim = [app.freq(end) - maxLim*app.zoomxFactor2,app.freq(end)];
                    else
                        app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    end
                case 3
                    app.PlusxButton1Pushed(app);
                case 4
                    maxLim = app.freq(end);
                    
                    if app.center2 == 0
                        centro = mean(app.UIAxes2.XLim);
                    else
                        centro = app.center2;
                    end
                    
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    if app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    end
                    
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        app.UIAxes2.XLim = [0, maxLim*app.zoomxFactor2];
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        app.UIAxes2.XLim = [app.freq(end) - maxLim*app.zoomxFactor2,app.freq(end)];
                    else
                        app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    end
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: MinusxButton2
        function MinusxButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.XLim);
                    
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    if app.zoomxFactor2 > 0.91
                        app.MinusxButton2.Enable = 'off';
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                case 3
                    app.MinusxButton1Pushed(app);
                case 4
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.XLim);
                    
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    if app.zoomxFactor2 > 0.91
                        app.MinusxButton2.Enable = 'off';
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    app.UIAxes2.XLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: PlusyButton2
        function PlusyButton2Pushed(app, event)
            switch app.mode
                case {1,2,4}
                    maxLim = app.yLim2(2) - app.yLim2(1);
                    centro = mean(app.UIAxes2.YLim);
                    
                    app.zoomyFactor2 = app.zoomyFactor2 - 0.1;
                    
                    if app.zoomyFactor2 < 0.11
                        app.PlusyButton2.Enable = 'off';
                    elseif app.zoomyFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    end
                    
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomyFactor2/2,centro+maxLim*app.zoomyFactor2/2];
                case 3
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.YLim);
                    
                    if app.zoomxFactor2 > 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.1;
                    elseif app.zoomxFactor2 < 0.11
                        app.zoomxFactor2 = app.zoomxFactor2 - 0.01;
                    end
                    
                    if app.zoomxFactor2 < 0.011
                        app.PlusyButton2.Enable = 'off';
                    elseif app.zoomxFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    end
                    
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
            end
        end

        % Button pushed function: MinusyButton2
        function MinusyButton2Pushed(app, event)
            switch app.mode
                case {1,2,4}
                    maxLim = app.yLim2(2) - app.yLim2(1);
                    centro = mean(app.UIAxes2.YLim);
                    
                    app.zoomyFactor2 = app.zoomyFactor2 + 0.1;
                    
                    if app.zoomyFactor2 > 0.91
                        app.MinusyButton2.Enable = 'off';
                    elseif app.zoomyFactor2 > 0.11
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomyFactor2/2,centro+maxLim*app.zoomyFactor2/2];
                case 3
                    maxLim = app.freq(end);
                    centro = mean(app.UIAxes2.YLim);
                    
                    if app.zoomxFactor2 > 0.09
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.1;
                    else
                        app.zoomxFactor2 = app.zoomxFactor2 + 0.01;
                    end
                    
                    if app.zoomxFactor2 > 0.91
                        app.MinusyButton2.Enable = 'off';
                    elseif app.zoomxFactor2 > 0.011
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    if centro-maxLim*app.zoomxFactor2/2 < 0
                        centro = maxLim*app.zoomxFactor2/2;
                    elseif centro+maxLim*app.zoomxFactor2/2 > app.freq(end)
                        centro = app.freq(end) - maxLim*app.zoomxFactor2/2;
                    end
                    
                    app.UIAxes2.YLim = [centro-maxLim*app.zoomxFactor2/2,centro+maxLim*app.zoomxFactor2/2];
            end
        end

        % Button pushed function: ResetButton2
        function ResetButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    app.UIAxes2.XLim = app.xLim2;
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.xLim2 = app.UIAxes2.XLim;
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                    
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                case 3
                    app.UIAxes2.YLim = app.xLim2;
                    app.UIAxes2.XLim = app.UIAxes.XLim;
                    
                    app.PlusxButton2.Enable = app.PlusxButton1.Enable;
                    app.MinusxButton2.Enable = app.MinusxButton1.Enable;
                    
                    app.zoomxFactor2 = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/app.freq(end);
                    if  app.zoomxFactor2 < 0.011
                        app.PlusyButton2.Enable = 'off';
                    else
                        app.PlusyButton2.Enable = 'on';
                    end
                    
                    if app.zoomxFactor2 < 0.91
                        app.MinusyButton2.Enable = 'on';
                    else
                        app.MinusyButton2.Enable = 'off';
                    end
                case 4
                    app.UIAxes2.XLim = app.xLim2;
                    app.UIAxes2.YLim(1) = 0;
                    app.UIAxes2.YLim(2) = max(app.spectrum);
                    app.xLim2 = app.UIAxes2.XLim;
                    app.yLim2 = app.UIAxes2.YLim;
                    app.zoomyFactor2 = 1;
                    app.zoomxFactor2 = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/app.freq(end);
                    if  app.zoomxFactor2 < 0.011
                        app.PlusxButton2.Enable = 'off';
                    else
                        app.PlusxButton2.Enable = 'on';
                    end
                    
                    if app.zoomxFactor2 < 0.91
                        app.MinusxButton2.Enable = 'on';
                    else
                        app.MinusxButton2.Enable = 'off';
                    end
                    
                    app.MinusyButton2.Enable = 'off';
                    app.PlusyButton2.Enable = 'on';
                    
                    app.UIAxes3.XLim = app.UIAxes.XLim;
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
            end
        end

        % Button pushed function: RightButton2
        function RightButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    if round(app.UIAxes2.XLim(2) + step) > app.freq(end)
                        app.UIAxes2.XLim = [(app.freq(end) - (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim + step;
                    end
                    
                    app.UIAxes2.YLim = yLim;
                case 3
                    app.RightButton1Pushed(app);
                case 4
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    if round(app.UIAxes2.XLim(2) + step) > app.freq(end)
                        app.UIAxes2.XLim = [(app.freq(end) - (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim + step;
                    end
                    
                    app.UIAxes2.YLim = yLim;
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
                    app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: LeftButton2
        function LeftButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    if round(app.UIAxes2.XLim(1) - step) < 0
                        app.UIAxes2.XLim = [0 (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim - step;
                    end
                    
                    app.UIAxes2.YLim = yLim;
                case 3
                    app.LeftButton1Pushed(app);
                case 4
                    step = (app.UIAxes2.XLim(2)-app.UIAxes2.XLim(1))/4;
                    
                    yLim = app.UIAxes2.YLim;
                    
                    if round(app.UIAxes2.XLim(1) - step) < 0
                        app.UIAxes2.XLim = [0 (app.UIAxes2.XLim(2) - app.UIAxes2.XLim(1))];
                    else
                        app.UIAxes2.XLim = app.UIAxes2.XLim - step;
                    end
                    
                    app.UIAxes2.YLim = yLim;
                    
                    app.UIAxes3.YLim = app.UIAxes2.XLim;
                    app.UIAxes3.XLim = app.UIAxes.XLim;
            end
        end

        % Button pushed function: UpButton2
        function UpButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    step = (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))/4;
                    xLim = app.UIAxes2.XLim;
                    
                    if round(app.UIAxes2.YLim(2) + step,3) > app.yLim2(2)
                        app.UIAxes2.YLim = [app.yLim2(2) - (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1)),app.yLim2(2)];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim + step;
                    end
                    
                    app.UIAxes2.XLim = xLim;
                case 3
                    step = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/4;
                    
                    xLim = app.UIAxes2.XLim;
                    
                    if round(app.UIAxes2.YLim(2) + step) > app.freq(end)
                        app.UIAxes2.YLim = [(app.freq(end) - (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))),app.freq(end)];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim + step;
                    end
                    
                    app.UIAxes2.XLim = xLim;
                case 4
                    
            end
        end

        % Button pushed function: DownButton2
        function DownButton2Pushed(app, event)
            switch app.mode
                case {1,2}
                    step = (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))/4;
                    xLim = app.UIAxes2.XLim;
                    
                    if round(app.UIAxes2.YLim(1) - step,3) < 0
                        app.UIAxes2.YLim = [0,(app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim - step;
                    end
                    
                    app.UIAxes2.XLim = xLim;
                case 3
                    step = (app.UIAxes2.YLim(2)-app.UIAxes2.YLim(1))/4;
                    
                    xLim = app.UIAxes2.XLim;
                    
                    if round(app.UIAxes2.YLim(1) - step) < 0
                        app.UIAxes2.YLim = [0 (app.UIAxes2.YLim(2) - app.UIAxes2.YLim(1))];
                    else
                        app.UIAxes2.YLim = app.UIAxes2.YLim - step;
                    end
                    
                    app.UIAxes2.XLim = xLim;
                case 4
                    
            end
        end

        % Button pushed function: playSegmentButton
        function playSegmentButtonPushed(app, event)
            play(app.player,app.startPlaying)
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            app.CallingApp.createHelp(9); % 9 = Visualization
        end

        % Button pushed function: exportButton1
        function exportButton1Pushed(app, event)
            % Exportar datos de señal
            [file,path] = uiputfile({'*.wav';'*.csv';'*.xls'},app.export_signal);
            if ~(isequal(file,0) || isequal(path,0))  % Si el usuario no cancela
                
                if contains(file,'.wav') % Si es un fichero .wav
                    signalAux = detrend(app.signal); % Elimina el offset, si lo tiene
                    
                    if(max(abs(signalAux)) > 1) % Normaliza la seña l si su amplitud es > 1
                        signalAux = signalAux./max(abs(signalAux));
                    end
                    
                    audiowrite(fullfile(path,file),signalAux,app.fs); % Escribe fichero wav
                    
                else % Si es un fichero .csv o .xls
                    writematrix(app.signal,fullfile(path,file));
                end
            end
        end

        % Button pushed function: exportButton2
        function exportButton2Pushed(app, event)
            % Exportar datos de espectro
            [file,path] = uiputfile({'*.csv';'*.xls'},app.export_spectrum);
            if ~(isequal(file,0) || isequal(path,0))  % Si el usuario no cancela
                writematrix(app.signal,fullfile(path,file))
            end
        end

        % Button pushed function: restore_layout2
        function restore_layout2ButtonPushed(app, event)
            % Cambiar tamaño de ambos ejes
            app.UIAxes.Position = app.axes1_layout2;
            app.UIAxes2.Position = app.axes2_layout2;
            
            % Hacer los dos ejes visibles
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            
            % Hacer ambos paneles de control visibles
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: up_layout2
        function up_layout2ButtonPushed(app, event)
            % Agrandar el eje 1 y esconder el eje 2
            app.UIAxes.Position = app.mainPlot_size;
            app.UIAxes2.Position(3:4) = [0 0];
            
            % Hacer visible el eje 1 y esconder el eje 2
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'off';
            
            % Hacer visible el panel de control 1 y esconder el 2
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'off';
        end

        % Button pushed function: down_layout2
        function down_layout2ButtonPushed(app, event)
            % Agrandar el eje 2 y esconder el eje 1
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position = app.mainPlot_size;
            
            % Hacer visible el eje 2 y esconder el eje 1
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'on';
            
            % Hacer visible el panel de control 2 y esconder el 1
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: restore_layout3
        function restore_layout3ButtonPushed(app, event)
            % Restaurar el tamaño de los 3 ejes
            app.UIAxes.Position = app.axes1_layout3;
            app.UIAxes2.Position = app.axes2_layout3;
            app.UIAxes3.Position = app.axes3_layout3;
            
            % Hacer visibles los tres ejes
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'on';
            
            % Hacer visibles los dos paneles de control
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: up_layout3
        function up_layout3ButtonPushed(app, event)
            % Agrandar el eje 1 y esconder los otros dos
            app.UIAxes.Position = app.mainPlot_size;
            app.UIAxes2.Position(3:4) = [0 0];
            app.UIAxes3.Position(3:4) = [0 0];
            
            % Hacer visible el eje 1 y esconder los otros dos
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            
            % Hacer visible panel 1 y esconder el 2
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'off';
        end

        % Button pushed function: middle_layout3
        function middle_layout3ButtonPushed(app, event)
            % Agrandar el eje 2 y esconder los otros dos
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position = app.mainPlot_size;
            app.UIAxes3.Position(3:4) = [0 0];
            
            % Hacer visible el eje 2 y esconder los otros dos
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            
            % Mostrar panel de control 2 y ocultar el 1
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'on';
        end

        % Button pushed function: down_layout3
        function down_layout3ButtonPushed(app, event)
            % Agrandar el eje 3 y esconder los otros dos
            app.UIAxes.Position(3:4) = [0 0];
            app.UIAxes2.Position(3:4) = [0 0];
            app.UIAxes3.Position = app.mainPlot_size;
            
            % Hacer visible el eje 3 y ocultar el otro
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
        end

        % Callback function
        function LinealMenuSelected(app, event)
            app.UIAxes.YScale = 'linear';
        end

        % Callback function
        function LogartmicaMenuSelected(app, event)
            app.UIAxes.YScale = 'log';
        end

        % Menu selected function: LinealMenu_2
        function LinealMenu_2Selected(app, event)
            app.yScale2 = 'linear';
            
            if app.mode == 3
                calculateSpectrogram(app);
            else
                app.UIAxes2.YScale = 'linear';
                app.amp_spectrum_label = app.amp_label;
                app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            end
        end

        % Menu selected function: LogartmicaMenu_2
        function LogartmicaMenu_2Selected(app, event)
            app.yScale2 = 'log';
            app.amp_spectrum_label = strcat(app.amp_label,' (dB)');
            
            if app.mode == 3
                calculateSpectrogram(app);
            else
                app.UIAxes2.YScale = 'log';                
                app.UIAxes2.YLabel.String = app.amp_spectrum_label;
            end
        end

        % Menu selected function: GuardarcomoMenu
        function GuardarcomoMenuSelected(app, event)
            % Exportar plot de UIAxes como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes))
        end

        % Menu selected function: CopiarcomomapadebitsMenu
        function CopiarcomomapadebitsMenuSelected(app, event)
            % Copiar plot de UIAxes como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu
        function CopiarcomoimagenvectorialMenuSelected(app, event)
            % Copiar plot de UIAxes como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes),'target','clipboard','format','vector','vector',true)
        end

        % Menu selected function: GuardarcomoMenu_2
        function GuardarcomoMenu_2Selected(app, event)
            % Exportar plot de UIAxes2 como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes2))
        end

        % Menu selected function: CopiarcomomapadebitsMenu_2
        function CopiarcomomapadebitsMenu_2Selected(app, event)
            % Copiar plot de UIAxes2 como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes2),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu_2
        function CopiarcomoimagenvectorialMenu_2Selected(app, event)
            % Copiar plot de UIAxes2 como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes2),'target','clipboard','format','vector','vector',true)
        end

        % Menu selected function: GuardarcomoMenu_3
        function GuardarcomoMenu_3Selected(app, event)
            % Exportar plot de UIAxes3 como imagen
            matlab.graphics.internal.export.exportCallback(matlab.graphics.controls.internal.exportHelper(app.UIAxes3))
        end

        % Menu selected function: CopiarcomomapadebitsMenu_3
        function CopiarcomomapadebitsMenu_3Selected(app, event)
            % Copiar plot de UIAxes3 como mapa de bits
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes3),'target','clipboard','format','image')
        end

        % Menu selected function: CopiarcomoimagenvectorialMenu_3
        function CopiarcomoimagenvectorialMenu_3Selected(app, event)
            % Copiar plot de UIAxes3 como imagen vectorial
            matlab.graphics.internal.export.exportTo(matlab.graphics.controls.internal.exportHelper(app.UIAxes3),'target','clipboard','format','vector','vector',true)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.VentanaDropDown = uidropdown(app.UIFigure);
            app.VentanaDropDown.Items = {'Rectangular', 'Hanning', 'Hamming', 'Gaussiana', 'Triangular', 'Chebyshev', 'Taylor', 'Bartlett', 'Kaiser'};
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Enable = 'off';
            app.VentanaDropDown.Visible = 'off';
            app.VentanaDropDown.Value = 'Rectangular';
            app.nfftDropDown = uidropdown(app.UIFigure);
            app.nfftDropDown.ValueChangedFcn = createCallbackFcn(app, @nfftDropDownValueChanged, true);
            app.nfftDropDown.Enable = 'off';
            app.nfftDropDown.Visible = 'off';
            app.TamaodeventanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeventanaEditField.ValueChangedFcn = createCallbackFcn(app, @TamaodeventanaEditFieldValueChanged, true);
            app.TamaodeventanaEditField.Enable = 'off';
            app.TamaodeventanaEditField.Visible = 'off';
            app.TamaodeventanaEditField.Value = 10001;
            app.VentanaLabel = uilabel(app.UIFigure);
            app.VentanaLabel.Visible = 'off';
            app.VentanaLabel.Text = 'Ventana';
            app.TamaodeventanaLabel = uilabel(app.UIFigure);
            app.TamaodeventanaLabel.Visible = 'off';
            app.TamaodeventanaLabel.Text = 'Tamaño de ventana';
            app.nfftLabel = uilabel(app.UIFigure);
            app.nfftLabel.Visible = 'off';
            app.nfftLabel.Text = 'nfft';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'FT', 'STFT', 'Espectrograma', 'STFT + Espect.'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'FT';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Forma de onda')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Espectro')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Amplitud')
            app.UIAxes2.YScale = 'log';
            app.Panel = uipanel(app.UIFigure);
            app.PlusxButton1 = uibutton(app.Panel, 'push');
            app.PlusxButton1.ButtonPushedFcn = createCallbackFcn(app, @PlusxButton1Pushed, true);
            app.PlusxButton1.Icon = 'zoomright.png';
            app.PlusxButton1.Text = '';
            app.MinusxButton1 = uibutton(app.Panel, 'push');
            app.MinusxButton1.ButtonPushedFcn = createCallbackFcn(app, @MinusxButton1Pushed, true);
            app.MinusxButton1.Icon = 'zoomleft.png';
            app.MinusxButton1.Enable = 'off';
            app.MinusxButton1.Text = '';
            app.PlusyButton1 = uibutton(app.Panel, 'push');
            app.PlusyButton1.ButtonPushedFcn = createCallbackFcn(app, @PlusyButton1Pushed, true);
            app.PlusyButton1.Icon = 'zoomup.png';
            app.PlusyButton1.Text = '';
            app.MinusyButton1 = uibutton(app.Panel, 'push');
            app.MinusyButton1.ButtonPushedFcn = createCallbackFcn(app, @MinusyButton1Pushed, true);
            app.MinusyButton1.Icon = 'zoomdown.png';
            app.MinusyButton1.Enable = 'off';
            app.MinusyButton1.Text = '';
            app.UpButton1 = uibutton(app.Panel, 'push');
            app.UpButton1.ButtonPushedFcn = createCallbackFcn(app, @UpButton1Pushed, true);
            app.UpButton1.Icon = 'up.png';
            app.UpButton1.Text = '';
            app.DownButton1 = uibutton(app.Panel, 'push');
            app.DownButton1.ButtonPushedFcn = createCallbackFcn(app, @DownButton1Pushed, true);
            app.DownButton1.Icon = 'down.png';
            app.DownButton1.Text = '';
            app.RightButton1 = uibutton(app.Panel, 'push');
            app.RightButton1.ButtonPushedFcn = createCallbackFcn(app, @RightButton1Pushed, true);
            app.RightButton1.Icon = 'right.png';
            app.RightButton1.Text = '';
            app.LeftButton1 = uibutton(app.Panel, 'push');
            app.LeftButton1.ButtonPushedFcn = createCallbackFcn(app, @LeftButton1Pushed, true);
            app.LeftButton1.Icon = 'left.png';
            app.LeftButton1.Text = '';
            app.ResetButton1 = uibutton(app.Panel, 'push');
            app.ResetButton1.ButtonPushedFcn = createCallbackFcn(app, @ResetButton1Pushed, true);
            app.ResetButton1.Icon = 'zoomcenter.png';
            app.ResetButton1.Text = '';
            app.exportButton1 = uibutton(app.Panel, 'push');
            app.exportButton1.ButtonPushedFcn = createCallbackFcn(app, @exportButton1Pushed, true);
            app.exportButton1.Icon = 'export.png';
            app.exportButton1.Text = '';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PlusxButton2 = uibutton(app.Panel_2, 'push');
            app.PlusxButton2.ButtonPushedFcn = createCallbackFcn(app, @PlusxButton2Pushed, true);
            app.PlusxButton2.Icon = 'zoomright.png';
            app.PlusxButton2.Text = '';
            app.MinusxButton2 = uibutton(app.Panel_2, 'push');
            app.MinusxButton2.ButtonPushedFcn = createCallbackFcn(app, @MinusxButton2Pushed, true);
            app.MinusxButton2.Icon = 'zoomleft.png';
            app.MinusxButton2.Enable = 'off';
            app.MinusxButton2.Text = '';
            app.PlusyButton2 = uibutton(app.Panel_2, 'push');
            app.PlusyButton2.ButtonPushedFcn = createCallbackFcn(app, @PlusyButton2Pushed, true);
            app.PlusyButton2.Icon = 'zoomup.png';
            app.PlusyButton2.Text = '';
            app.MinusyButton2 = uibutton(app.Panel_2, 'push');
            app.MinusyButton2.ButtonPushedFcn = createCallbackFcn(app, @MinusyButton2Pushed, true);
            app.MinusyButton2.Icon = 'zoomdown.png';
            app.MinusyButton2.Enable = 'off';
            app.MinusyButton2.Text = '';
            app.UpButton2 = uibutton(app.Panel_2, 'push');
            app.UpButton2.ButtonPushedFcn = createCallbackFcn(app, @UpButton2Pushed, true);
            app.UpButton2.Icon = 'up.png';
            app.UpButton2.Text = '';
            app.DownButton2 = uibutton(app.Panel_2, 'push');
            app.DownButton2.ButtonPushedFcn = createCallbackFcn(app, @DownButton2Pushed, true);
            app.DownButton2.Icon = 'down.png';
            app.DownButton2.Text = '';
            app.RightButton2 = uibutton(app.Panel_2, 'push');
            app.RightButton2.ButtonPushedFcn = createCallbackFcn(app, @RightButton2Pushed, true);
            app.RightButton2.Icon = 'right.png';
            app.RightButton2.Text = '';
            app.LeftButton2 = uibutton(app.Panel_2, 'push');
            app.LeftButton2.ButtonPushedFcn = createCallbackFcn(app, @LeftButton2Pushed, true);
            app.LeftButton2.Icon = 'left.png';
            app.LeftButton2.Text = '';
            app.ResetButton2 = uibutton(app.Panel_2, 'push');
            app.ResetButton2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton2Pushed, true);
            app.ResetButton2.Icon = 'zoomcenter.png';
            app.ResetButton2.Text = '';
            app.exportButton2 = uibutton(app.Panel_2, 'push');
            app.exportButton2.ButtonPushedFcn = createCallbackFcn(app, @exportButton2Pushed, true);
            app.exportButton2.Icon = 'export.png';
            app.exportButton2.Text = '';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Icon = 'info.png';
            app.infoButton.Text = '';
            app.Panel_Reproduccion = uipanel(app.UIFigure);
            app.stopButton = uibutton(app.Panel_Reproduccion, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_button.png';
            app.stopButton.Enable = 'off';
            app.stopButton.Text = '';
            app.playSegmentButton = uibutton(app.Panel_Reproduccion, 'push');
            app.playSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @playSegmentButtonPushed, true);
            app.playSegmentButton.Icon = 'playSegment_button.png';
            app.playSegmentButton.Enable = 'off';
            app.playSegmentButton.Text = {''; ''};
            app.playButton = uibutton(app.Panel_Reproduccion, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play_button.png';
            app.playButton.Enable = 'off';
            app.playButton.Text = {''; ''};
            app.Panel_layout_3 = uipanel(app.UIFigure);
            app.Panel_layout_3.Visible = 'off';
            app.middle_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.middle_layout3.ButtonPushedFcn = createCallbackFcn(app, @middle_layout3ButtonPushed, true);
            app.middle_layout3.Icon = 'medium_3_layout.png';
            app.middle_layout3.Text = '';
            app.up_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.up_layout3.ButtonPushedFcn = createCallbackFcn(app, @up_layout3ButtonPushed, true);
            app.up_layout3.Icon = 'up_3_layout.png';
            app.up_layout3.Text = {''; ''};
            app.restore_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.restore_layout3.ButtonPushedFcn = createCallbackFcn(app, @restore_layout3ButtonPushed, true);
            app.restore_layout3.Icon = 'restore_3_layout.png';
            app.restore_layout3.Text = {''; ''};
            app.down_layout3 = uibutton(app.Panel_layout_3, 'push');
            app.down_layout3.ButtonPushedFcn = createCallbackFcn(app, @down_layout3ButtonPushed, true);
            app.down_layout3.Icon = 'down_3_layout.png';
            app.down_layout3.Text = '';
            app.Panel_layout_2 = uipanel(app.UIFigure);
            app.down_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.down_layout2.ButtonPushedFcn = createCallbackFcn(app, @down_layout2ButtonPushed, true);
            app.down_layout2.Icon = 'down_2_layout.png';
            app.down_layout2.Text = '';
            app.up_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.up_layout2.ButtonPushedFcn = createCallbackFcn(app, @up_layout2ButtonPushed, true);
            app.up_layout2.Icon = 'up_2_layout.png';
            app.up_layout2.Text = {''; ''};
            app.restore_layout2 = uibutton(app.Panel_layout_2, 'push');
            app.restore_layout2.ButtonPushedFcn = createCallbackFcn(app, @restore_layout2ButtonPushed, true);
            app.restore_layout2.Icon = 'restore_2_layout.png';
            app.restore_layout2.Text = {''; ''};
            app.fsField = uieditfield(app.UIFigure, 'numeric');
            app.fsField.ValueDisplayFormat = '%.0f';
            app.fsField.Editable = 'off';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs:';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ExportarimagenMenu_UIAxes = uimenu(app.ContextMenu);
            app.ExportarimagenMenu_UIAxes.Text = 'Exportar imagen';
            app.GuardarcomoMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.GuardarcomoMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenuSelected, true);
            app.GuardarcomoMenu.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.CopiarcomomapadebitsMenu.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenuSelected, true);
            app.CopiarcomomapadebitsMenu.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu = uimenu(app.ExportarimagenMenu_UIAxes);
            app.CopiarcomoimagenvectorialMenu.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenuSelected, true);
            app.CopiarcomoimagenvectorialMenu.Text = 'Copiar como imagen vectorial';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.UIAxes2.ContextMenu = app.ContextMenu2;
            app.EscaladelejeYMenu_UIAxes2 = uimenu(app.ContextMenu2);
            app.EscaladelejeYMenu_UIAxes2.Text = 'Escala del eje Y';
            app.LinealMenu_2 = uimenu(app.EscaladelejeYMenu_UIAxes2);
            app.LinealMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LinealMenu_2Selected, true);
            app.LinealMenu_2.Text = 'Lineal';
            app.LogartmicaMenu_2 = uimenu(app.EscaladelejeYMenu_UIAxes2);
            app.LogartmicaMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LogartmicaMenu_2Selected, true);
            app.LogartmicaMenu_2.Text = 'Logarítmica';
            app.ExportarimagenMenu_UIAxes2 = uimenu(app.ContextMenu2);
            app.ExportarimagenMenu_UIAxes2.Text = 'Exportar imagen';
            app.GuardarcomoMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.GuardarcomoMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenu_2Selected, true);
            app.GuardarcomoMenu_2.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.CopiarcomomapadebitsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenu_2Selected, true);
            app.CopiarcomomapadebitsMenu_2.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu_2 = uimenu(app.ExportarimagenMenu_UIAxes2);
            app.CopiarcomoimagenvectorialMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenu_2Selected, true);
            app.CopiarcomoimagenvectorialMenu_2.Text = 'Copiar como imagen vectorial';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu3
            app.UIAxes3.ContextMenu = app.ContextMenu3;
            app.ExportarimagenMenu_UIAxes3 = uimenu(app.ContextMenu3);
            app.ExportarimagenMenu_UIAxes3.Text = 'Exportar imagen';
            app.GuardarcomoMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.GuardarcomoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @GuardarcomoMenu_3Selected, true);
            app.GuardarcomoMenu_3.Text = 'Guardar como...';
            app.CopiarcomomapadebitsMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.CopiarcomomapadebitsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomomapadebitsMenu_3Selected, true);
            app.CopiarcomomapadebitsMenu_3.Text = 'Copiar como mapa de bits';
            app.CopiarcomoimagenvectorialMenu_3 = uimenu(app.ExportarimagenMenu_UIAxes3);
            app.CopiarcomoimagenvectorialMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CopiarcomoimagenvectorialMenu_3Selected, true);
            app.CopiarcomoimagenvectorialMenu_3.Text = 'Copiar como imagen vectorial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualization_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end